classdef NV_ExperimentEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RightPanel                      matlab.ui.container.Panel
        Panel                           matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        AbortButton                     matlab.ui.control.Button
        ScreenshotButton                matlab.ui.control.Button
        StatusLamp                      matlab.ui.control.Lamp
        ColorBarPanel                   matlab.ui.container.Panel
        OutputInfo                      matlab.ui.control.TextArea
        ResetButton                     matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        OutputPanel                     matlab.ui.container.Panel
        TwoDPlot                        matlab.ui.control.UIAxes
        PulseSequencePlot               matlab.ui.control.UIAxes
        OneDPlot                        matlab.ui.control.UIAxes
        LeftPanel                       matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        ConnectionsTab                  matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        WindfreakRFLoad                 matlab.ui.control.Button
        WindfreakRFLamp                 matlab.ui.control.Lamp
        WindfreakRFUnload               matlab.ui.control.Button
        WindfreakRFConfig               matlab.ui.control.EditField
        WindfreakRFLabel                matlab.ui.control.Label
        DAQConfig                       matlab.ui.control.EditField
        SRSRFConfig                     matlab.ui.control.EditField
        SproutLaserConfig               matlab.ui.control.EditField
        PulseBlasterConfig              matlab.ui.control.EditField
        StageConfig                     matlab.ui.control.EditField
        ConfigFileNameLabel             matlab.ui.control.Label
        DAQLabel                        matlab.ui.control.Label
        SRSRFLabel                      matlab.ui.control.Label
        SproutLaserLabel                matlab.ui.control.Label
        PulseBlasterLabel               matlab.ui.control.Label
        StageLabel                      matlab.ui.control.Label
        SproutLaserLamp                 matlab.ui.control.Lamp
        SproutLaserUnload               matlab.ui.control.Button
        SproutLaserLoad                 matlab.ui.control.Button
        DAQUnload                       matlab.ui.control.Button
        DAQLoad                         matlab.ui.control.Button
        SRSRFUnload                     matlab.ui.control.Button
        PulseBlasterUnload              matlab.ui.control.Button
        DAQLamp                         matlab.ui.control.Lamp
        SRSRFLamp                       matlab.ui.control.Lamp
        PulseBlasterLamp                matlab.ui.control.Lamp
        SRSRFLoad                       matlab.ui.control.Button
        PulseBlasterLoad                matlab.ui.control.Button
        StageLamp                       matlab.ui.control.Lamp
        StageUnload                     matlab.ui.control.Button
        StageLoad                       matlab.ui.control.Button
        SettingsTab                     matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        DatapointdurationnsEditField    matlab.ui.control.NumericEditField
        DatapointdurationLabel          matlab.ui.control.Label
        AmplitudedBmLabel               matlab.ui.control.Label
        WindfreakRFsettingsLabel        matlab.ui.control.Label
        SRSRFsettingsLabel              matlab.ui.control.Label
        windfreakRFModulationDropDown   matlab.ui.control.DropDown
        ModulationLabel                 matlab.ui.control.Label
        windfreakRFAmplitude            matlab.ui.control.NumericEditField
        windfreakRFFrequency            matlab.ui.control.NumericEditField
        RFFrequencyGHzLabel_2           matlab.ui.control.Label
        NumberofpointsforcontinuouscollectionEditField  matlab.ui.control.NumericEditField
        NumberofpointsforcontinuouscollectionLabel  matlab.ui.control.Label
        SRS_RFModulationDropDown        matlab.ui.control.DropDown
        OffWhenDeleted                  matlab.ui.control.CheckBox
        WaitForLaserEquilibrium         matlab.ui.control.CheckBox
        DifferentiatesignalandreferenceCheckBox  matlab.ui.control.CheckBox
        SRS_RFAmplitude                 matlab.ui.control.NumericEditField
        SRS_RFFrequency                 matlab.ui.control.NumericEditField
        CopyappobjecttoworkspaceButton  matlab.ui.control.Button
        CollectdatacontinuouslyButton   matlab.ui.control.Button
        RefreshLaserPowerReading        matlab.ui.control.Button
        LaserPowerReading               matlab.ui.control.NumericEditField
        PowerreadingmWLabel             matlab.ui.control.Label
        SetpowermWLabel                 matlab.ui.control.Label
        RefreshAppButton                matlab.ui.control.Button
        ToggleLaser                     matlab.ui.control.Switch
        LaserLabel_2                    matlab.ui.control.Label
        SetLaserPower                   matlab.ui.control.NumericEditField
        PositionTab                     matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        OptimizationInsertorReplace     matlab.ui.control.DropDown
        OptimizationPercentage          matlab.ui.control.NumericEditField
        TimebetweenoptimizationsminLabel_2  matlab.ui.control.Label
        OptimizationPercentageCheck     matlab.ui.control.CheckBox
        OptimizationTimer               matlab.ui.control.CheckBox
        OptimizationparameterDropDown   matlab.ui.control.DropDown
        OptimizationparameterLabel      matlab.ui.control.Label
        ZFineReset                      matlab.ui.control.Button
        YFineReset                      matlab.ui.control.Button
        XFineReset                      matlab.ui.control.Button
        ResetFineAxisLabel              matlab.ui.control.Label
        StageSettlingTime               matlab.ui.control.NumericEditField
        PausedurationforfinestagemovementsmsLabel  matlab.ui.control.Label
        OptimizationmethodDropDown      matlab.ui.control.DropDown
        OptimizationmethodDropDownLabel  matlab.ui.control.Label
        Numberofmanualoptimizations     matlab.ui.control.NumericEditField
        NumberofmanualoptimizationsLabel  matlab.ui.control.Label
        BeginmanualoptimizationButton   matlab.ui.control.Button
        OptimizationInterval            matlab.ui.control.NumericEditField
        TimebetweenoptimizationsminLabel  matlab.ui.control.Label
        StageAutoOptimization           matlab.ui.control.CheckBox
        DeleteOptimizationStep          matlab.ui.control.Button
        AddOptimizationStep             matlab.ui.control.Button
        StageOptimizationInstructions   matlab.ui.control.Table
        StageAddress                    matlab.ui.control.NumericEditField
        AddressEditField_2Label         matlab.ui.control.Label
        ZIncrement                      matlab.ui.control.NumericEditField
        ZPosition                       matlab.ui.control.NumericEditField
        ZUp                             matlab.ui.control.Button
        YUp                             matlab.ui.control.Button
        ZDown                           matlab.ui.control.Button
        YDown                           matlab.ui.control.Button
        MovepositiveLabel               matlab.ui.control.Label
        MovenegativeLabel               matlab.ui.control.Label
        StagezLabel                     matlab.ui.control.Label
        StageyLabel                     matlab.ui.control.Label
        StagexLabel                     matlab.ui.control.Label
        AxisLabel                       matlab.ui.control.Label
        StageTolerance                  matlab.ui.control.NumericEditField
        StagetolerancemLabel            matlab.ui.control.Label
        YPosition                       matlab.ui.control.NumericEditField
        YIncrement                      matlab.ui.control.NumericEditField
        IncrementLabel                  matlab.ui.control.Label
        AbsolutepositionLabel           matlab.ui.control.Label
        XPosition                       matlab.ui.control.NumericEditField
        XIncrement                      matlab.ui.control.NumericEditField
        XUp                             matlab.ui.control.Button
        XDown                           matlab.ui.control.Button
        ScansTab                        matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        DataType                        matlab.ui.control.DropDown
        DataTypeLabel                   matlab.ui.control.Label
        NonlinearscanfilenameEditField  matlab.ui.control.EditField
        NonlinearscanfilenameLabel      matlab.ui.control.Label
        IterationsType                  matlab.ui.control.DropDown
        IterationstypeDropDownLabel     matlab.ui.control.Label
        MaxIterations                   matlab.ui.control.NumericEditField
        MaxIterationsEditFieldLabel     matlab.ui.control.Label
        RandomizescanCheckBox           matlab.ui.control.CheckBox
        PossibletypesTextArea           matlab.ui.control.TextArea
        PossibletypesTextAreaLabel      matlab.ui.control.Label
        DefaultScanChange               matlab.ui.control.DropDown
        DefaultchangeDropDownLabel      matlab.ui.control.Label
        ScanAddress                     matlab.ui.control.NumericEditField
        AddressEditField_3Label         matlab.ui.control.Label
        DeleteScanStep                  matlab.ui.control.Button
        AddScanStep                     matlab.ui.control.Button
        ScanningTable                   matlab.ui.control.Table
        OutputTab                       matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        BaselineSubtraction             matlab.ui.control.NumericEditField
        BaselineSubtractionEditFieldLabel  matlab.ui.control.Label
        PulseNumToPlot                  matlab.ui.control.NumericEditField
        ScanforxaxisLabel_2             matlab.ui.control.Label
        ThingToPlot                     matlab.ui.control.DropDown
        ForceplotupdateButton           matlab.ui.control.Button
        ConverttocountssMaynotperfectlycalculateforsmalltimesCheckBox  matlab.ui.control.CheckBox
        ContrastType                    matlab.ui.control.DropDown
        ContrasttypeDropDownLabel       matlab.ui.control.Label
        yAxisScanNum                    matlab.ui.control.NumericEditField
        ScanforyaxisLabel               matlab.ui.control.Label
        xAxisScanNum                    matlab.ui.control.NumericEditField
        ScanforxaxisLabel               matlab.ui.control.Label
        StaticColorLimits               matlab.ui.control.Table
        UseStaticColorLimits            matlab.ui.control.CheckBox
        DisplayIterations               matlab.ui.control.DropDown
        ForcesquareaspectratioforimagesCheckBox  matlab.ui.control.CheckBox
        ScanProgress                    matlab.ui.control.TextArea
        ScanStepTable                   matlab.ui.control.Table
        PlotLimits                      matlab.ui.control.Table
        SaveLoadTab                     matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        UserName                        matlab.ui.control.DropDown
        LoadNames                       matlab.ui.control.ListBox
        LoadDates                       matlab.ui.control.ListBox
        DataSetupsSwitch                matlab.ui.control.Switch
        SavedUserNotes                  matlab.ui.control.TextArea
        LoadButton                      matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        SaveFolderName                  matlab.ui.control.EditField
        SavenameLabel                   matlab.ui.control.Label
        BoxAddress                      matlab.ui.control.EditField
        BoxaddressEditFieldLabel        matlab.ui.control.Label
        PulseSequenceTab                matlab.ui.container.Tab
        GridLayout10                    matlab.ui.container.GridLayout
        UserName2                       matlab.ui.control.DropDown
        CopyAddresses                   matlab.ui.control.EditField
        CopytoButton                    matlab.ui.control.StateButton
        PulseUserNotes                  matlab.ui.control.TextArea
        PulseSequenceNames              matlab.ui.control.ListBox
        PulseSaveName                   matlab.ui.control.EditField
        SavenameLabel_2                 matlab.ui.control.Label
        PulseBoxAddress                 matlab.ui.control.EditField
        BoxaddressEditFieldLabel_2      matlab.ui.control.Label
        LoadPulse                       matlab.ui.control.StateButton
        SavePulseSequence               matlab.ui.control.StateButton
        TotalLoops                      matlab.ui.control.NumericEditField
        LoopsOrDuration                 matlab.ui.control.DropDown
        DeleteStepButton                matlab.ui.control.StateButton
        AddStepButton                   matlab.ui.control.StateButton
        PulseAddress                    matlab.ui.control.NumericEditField
        PulseAddressEditFieldLabel      matlab.ui.control.Label
        PulseSequenceTable              matlab.ui.control.Table
        ObscureoutputsettingsTab        matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        TempdatapointdeviationEditField  matlab.ui.control.NumericEditField
        TempdatapointdeviationLabel     matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    properties
        userList = {'Common','Mikael','David','Lakshmy','Kyle','Swastik','Aksshay'}
        ex = experiment() %Contains information necesary to run experiment as well as all the instrument objects
        storedDataSettings
        storedSetupSettings
        scanStatus = 'reset'
        videoRunning = false 
        displayInfo        
        general
        performScanReset
    end

    %Things To do: 
    %Add scan could run trhough addScan function in experiment
    %Stage optimiazation table coould run through addStructindex or Modify
    %all Fields
    %add labels and color changes to pulse sequence plot
    %Saving and loading needs to be tested with all other instruments
    %make the number of loops box do something
    %allow for a longer binary string in pulse sequence

%     Why are there console messages about lacking connections on startup? Generally check on these
% Scans should also have instrument identifier (i.e. srs vs windfreak)
% What's going on with the number of loops? How do I set this? Copying app to workspace shows it isn't working. 
% 	Added to load pulse blaster. Added value changed function for nLoops
% 	Changed minimum for nLoops/Duration to 0 instead of 1 and disabled rounding fractional values
% 	Data point duration on settings tab shouldn't exist
% 	Load this setting into edit field when loading setup
% 	Add value changed for loops/duration
% Loading setups outputs to command window
% Set value to .005 step size and got warning it was changed to .005
% Should have dropdown for modulation waveform
% Font doesn't fit into boxes unless maximized. Probably matlab's fault
% Box name (pulse sequence) and box address (save/load) aren't synced
% Loading pulse sequence should change total duration
% data type should be "counter" not "digital"
% If no save name provided, should use selected name in field
% Units for plotting axes
% plotting SNR doesn't work
% If data fails to collect, app breaks. Should have try/catch
% Add forcedDelayTime
% Abort/Reset didn't actually reset scan and caused errors
% Added temp function for data point deviation. Should properly be saved/loaded and placed into correct spot
% If reset pressed before abort completes properly, it doesn't abort at all
% Bounds on plotting are strange. Generally too wide
% Screenshot button doesn't work
% Baseline subtraction added to output settings. Previously was wrong field type. When changed, should do reverse of previous subtraction, then do current subtraction
% Pressing load instruments too quickly results in some not loading properly (seen in DAQ after PB and SRS)
% Issue with plotting 1D with baseline subtraction too high. reducedData(reducedData == 0) = baselineValue;  gives 'left and right have different number of elements'
% Untested: what happens if start button pushed twice?
% When editing ongoing scan, gives error dialog but still changes number
% Add estimated time in scan tab
% Increase sig figs for RF frequency
% Added resetAllData function to reset button. That is the express purpose of this function lol
% Why do you have app.ex.scan.completed? This is the same as the odometer in app.ex.odometer
% SetupsOrData default now set in design view and defaults to setups
% Add displaying specific prior iterations along with average of all prior iterations
% Inconsistency changing max iterations downwards after already completing (finish iteration or not depending on aborting scan)
% Made aborting repeatedly not cause errors
% Binary changes in pulse sequence still doesn't work
% Make default pulse notes blank
% Make x axis output setting work for pulse sequence (idk if you were planning that already)
% Add display for number of samples collected
% Add right click functionality to delete saved data and setups (and change version number to match?)
% For screenshot, have tab where you can select information to display in saved name
% 
% Made changes to RF generator queries


    methods (Access = private)

        function AppUpdate(app)
            %Updates all pages of the app
            ScanUpdate(app)
            PositionUpdate(app)
            ConnectionsUpdate(app)
            GeneralUpdate(app)
            SaveDataUpdate(app)
            StatusUpdate(app)
            OutputSettingsUpdate(app)
            PulseSequenceUpdate(app)
        end

        function PulseSequenceUpdate(app)
            if ~isempty(app.ex.pulseBlaster)
                if ~isempty(app.ex.pulseBlaster.userSequence)
                    for ii = 1:length(app.ex.pulseBlaster.userSequence)

                        tableBinary(ii) = c2s(app.ex.pulseBlaster.userSequence(ii).channelsBinary); %#ok<*AGROW>
                        tableDirection(ii) = c2s(app.ex.pulseBlaster.userSequence(ii).directionType);
                        tableContext(ii) = c2s(app.ex.pulseBlaster.userSequence(ii).contextInfo);
                        tableNotes(ii) = c2s(app.ex.pulseBlaster.userSequence(ii).notes);
                        tableActive{ii} = c2s(join(app.ex.pulseBlaster.userSequence(ii).activeChannels));
                        if tableActive{ii} == ""
                            tableActive{ii} = "None";
                        end
                    end
                    tableNumOut = [app.ex.pulseBlaster.userSequence.numericalOutput];
                    tableDuration = [app.ex.pulseBlaster.userSequence.duration];

                    app.PulseSequenceTable.Data = [(1:numel(tableDuration))',tableActive',tableBinary',tableNumOut',tableDuration',tableDirection',tableContext',tableNotes'];
                    % app.ex.pulseBlaster.nTotalLoops = app.TotalLoops.Value;** Removed
                    sendToInstrument(app.ex.pulseBlaster)
                end
            end
            if app.PulseSequencePlot.Visible == "on"
                DisplayUpdate(app)
            end

            rootName = strcat(app.BoxAddress.Value,'\Backlund lab shared\data\',...
                app.UserName2.Value,'\Experiment Editor\Pulse Sequences');
            directory = dir(rootName);
            if numel(directory) >= 3
                directory = struct2cell(directory(3:end));
                recencyOrder = datetime(directory(3,:));
                [~,idx] = sort(recencyOrder,'descend');
                names = directory(1,:);
                names = names(idx);
                names(2:end+1) = names;

                names{1} = 'Pulse Sequence Names';
                %erases the .mat from the sequence name, not necessary but
                %looks nicer
                for ii = 1:numel(names)
                    names{ii} =c2s(names{ii});
                    names{ii} = erase(names{ii},".mat");
                    names{ii} = s2c(names{ii});
                end
                app.PulseSequenceNames.Items = names;

            else
                app.PulseSequenceNames.Items = {'Pulse Sequence Names'};
                app.PulseSequenceNames.Value = 'Pulse Sequence Names';
                return
            end

        end

        function ConnectionsUpdate(app)

            %Checks connected status of each instrument and makes corresponding lamp red (unconnected) or green (connected)
            if checkInstrumentStatus(app,'PIstage')
                app.StageLamp.Color = [0.25,0.61,0.25];%on, green
            else
                app.StageLamp.Color = [0.78,0.09,0.22];%off, red
            end

            if checkInstrumentStatus(app,'pulseBlaster')
                app.PulseBlasterLamp.Color = [0.25,0.61,0.25];
            else
                app.PulseBlasterLamp.Color = [0.78,0.09,0.22];
            end

            % if checkInstrumentStatus(app,'ndYAG')
            % app.SproutLaserLamp.Color = [0.25,0.61,0.25];
            % else
            app.SproutLaserLamp.Color = [0.78,0.09,0.22];
            % end

            if checkInstrumentStatus(app,'SRS_RF')
                app.SRSRFLamp.Color = [0.25,0.61,0.25];
            else
                app.SRSRFLamp.Color = [0.78,0.09,0.22];
            end

            if checkInstrumentStatus(app,'windfreak_RF')
                app.WindfreakRFLamp.Color = [0.25,0.61,0.25];
            else
                app.WindfreakRFLamp.Color = [0.78,0.09,0.22];
            end

            if checkInstrumentStatus(app,'DAQ')
                app.DAQLamp.Color = [0.25,0.61,0.25];
            else
                app.DAQLamp.Color = [0.78,0.09,0.22];
            end

        end

        function GeneralUpdate(app)
            %For each instrument, check if instrument is connected and, if not, display default values. If it is connected,
            %change the display of each option to the current setting
            %For binary settings (on/off), value from instrument object is converted into state usable by display method


            % if checkInstrumentStatus(app,'ndYAG')
            %     app.OffWhenDeleted.Value = instrumentType.discernOnOff(app.ex.ndYAG.offWhenDeleted);
            %     app.WaitForLaserEquilibrium.Value = instrumentType.discernOnOff(app.ex.ndYAG.waitForEquilibrium);
            %     app.ToggleLaser.Value = instrumentType.discernOnOff(app.ex.ndYAG.enabled);
            %     app.SetLaserPower.Value = app.ex.ndYAG.setPower;
            %     app.LaserPowerReading.Value = app.ex.ndYAG.actualPower;
            % else
            %     app.OffWhenDeleted.Value = false;
            %     app.WaitForLaserEquilibrium.Value = false;
            %     app.ToggleLaser.Value = 'off';
            %     app.SetLaserPower.Value = 0;
            %     app.LaserPowerReading.Value = false;
            % end

            if checkInstrumentStatus(app,'SRS_RF')
                app.SRS_RFFrequency.Value = app.ex.SRS_RF.frequency;
                app.SRS_RFAmplitude.Value = app.ex.SRS_RF.amplitude;
                if strcmpi("On",app.ex.SRS_RF.modulationEnabled)
                    app.SRS_RFModulationDropDown.Value = app.ex.SRS_RF.modulationType;
                else
                    app.SRS_RFModulationDropDown.Value = "Off";
                end
            else
                app.SRS_RFFrequency.Value = 0;
                app.SRS_RFAmplitude.Value = 0;
                app.SRS_RFModulationDropDown.Value = 'Off';
            end

            if checkInstrumentStatus(app,'windfreak_RF')
                app.windfreakRFFrequency.Value = app.ex.windfreak_RF.frequency;
                app.windfreakRFAmplitude.Value = app.ex.windfreak_RF.amplitude;
                if strcmpi("On",app.ex.windfreak_RF.modulationEnabled)
                    app.windfreakRFModulationDropDown.Value = app.ex.windfreak_RF.modulationType;
                else
                    app.windfreakRFModulationDropDown.Value = "Off";
                end
            else
                app.windfreakRFFrequency.Value = 0;
                app.windfreakRFAmplitude.Value = 0;
                app.windfreakRFModulationDropDown.Value = 'Off';
            end
        end

        function SaveDataUpdate(app)
            rootName = strcat(app.BoxAddress.Value,'\Backlund lab shared\data\',...
                app.UserName.Value,'\Experiment Editor\',app.DataSetupsSwitch.Value);
            firstDir = dir(rootName);
            if numel(firstDir) >= 3
                firstDir = struct2cell(firstDir(3:end));
                recencyOrder = datetime(firstDir(3,:));
                [~,idx] = sort(recencyOrder,'descend');
                firstNames = firstDir(1,:);
                firstNames = firstNames(idx);
                firstNames(2:end+1) = firstNames;

            else
                app.LoadNames.Items = {[app.DataSetupsSwitch.Value,' Names']};
                app.LoadDates.Items = {[app.DataSetupsSwitch.Value,' Dates']};
                app.LoadNames.Value = [app.DataSetupsSwitch.Value,' Names'];
                app.LoadDates.Value = [app.DataSetupsSwitch.Value,' Dates'];
                return
            end

            if strcmp(app.DataSetupsSwitch.Value,'Data')
                %List all dates
                firstNames{1} = [app.DataSetupsSwitch.Value,' Dates'];
                app.LoadDates.Items = firstNames;
                rootName = strcat(rootName,'\',app.LoadDates.Value);


            else %Setups
                %List all names
                firstNames{1} = [app.DataSetupsSwitch.Value,' Names'];
                app.LoadNames.Items = firstNames;
                rootName = strcat(rootName,'\',app.LoadNames.Value);
            end

            secondDir = dir(rootName);

            if numel(secondDir) >= 3
                secondDir = struct2cell(secondDir(3:end));
                recencyOrder = datetime(secondDir(3,:));
                [~,idx] = sort(recencyOrder,'descend');
                secondNames = secondDir(1,:);
                secondNames = secondNames(idx);
                secondNames(2:end+1) = secondNames;


                if strcmp(app.DataSetupsSwitch.Value,'Data')
                    secondNames{1} = [app.DataSetupsSwitch.Value,' Names'];
                    app.LoadNames.Items = secondNames;
                else
                    secondNames{1} = [app.DataSetupsSwitch.Value,' Dates'];
                    app.LoadDates.Items = secondNames;
                end

            elseif strcmp(app.DataSetupsSwitch.Value,'Data')
                app.LoadNames.Items = {[app.DataSetupsSwitch.Value,' Names']};
            else
                app.LoadDates.Items = {[app.DataSetupsSwitch.Value,' Dates']};
            end

            if ~ismember(app.LoadNames.Value,app.LoadNames.Items)
                app.LoadNames.Value = [app.DataSetupsSwitch.Value,' Names'];
            end

            app.LoadNames.Value = app.LoadNames.Value;

            if ~ismember(app.LoadDates.Value,app.LoadDates.Items)
                app.LoadDates.Value = [app.DataSetupsSwitch.Value,' Dates'];
            end

            app.LoadDates.Value = app.LoadDates.Value;

            %Loads the user notes when the file version is changed
            if ~strcmpi(app.LoadDates.Value,'Setups Dates') && ~strcmpi(app.LoadNames.Value,'Data Names')
                if strcmpi(app.DataSetupsSwitch.Value,'setups')
                    rootName = strcat(rootName,'\',app.LoadDates.Value,'\MatLab_User_Notes');
                else
                    rootName = strcat(rootName,'\',app.LoadNames.Value,'\MatLab_User_Notes');
                end
                load(rootName,"userNotes")
                app.SavedUserNotes.Value = userNotes{1};
            end

        end

        function PositionUpdate(app)
            %Updates the absolute position of stage axes
            if checkInstrumentStatus(app,'PIstage')
                app.XPosition.Value = app.ex.PIstage.axisSum{strcmp(app.ex.PIstage.axisSum(:,1),'X'),2};
                app.YPosition.Value = app.ex.PIstage.axisSum{strcmp(app.ex.PIstage.axisSum(:,1),'Y'),2};
                app.ZPosition.Value = app.ex.PIstage.axisSum{strcmp(app.ex.PIstage.axisSum(:,1),'Z'),2};
                app.StageTolerance.Value = app.ex.PIstage.tolerance;
            end


            app.StageAutoOptimization.Value = app.ex.optimizationInfo.enableOptimization;

            app.OptimizationInterval.Value = app.ex.optimizationInfo.timeBetweenOptimizations;

            app.OptimizationPercentage.Value = app.ex.optimizationInfo.percentageToForceOptimization;

            app.OptimizationTimer.Value = app.ex.optimizationInfo.useTimer;

            app.OptimizationPercentageCheck.Value = app.ex.optimizationInfo.usePercentageDifference;

            app.OptimizationmethodDropDown.Value = app.ex.optimizationInfo.algorithmType;

            if strcmp(app.ex.optimizationInfo.rfStatus, 'off')
                app.OptimizationparameterDropDown.Value = 'Counts (RF off)';
            elseif strcmp(app.ex.optimizationInfo.rfStatus, 'on')
                app.OptimizationparameterDropDown.Value = 'Counts (RF on)';
            else
                app.OptimizationparameterDropDown.Value = 'Contrast';
            end
            
            optSteps = cell2mat(app.ex.optimizationInfo.steps);
            app.StageOptimizationInstructions.Data = [app.ex.optimizationInfo.stageAxes' app.ex.optimizationInfo.radius' optSteps'];

        end

        function OutputSettingsUpdate(app)

            app.ForcesquareaspectratioforimagesCheckBox.Value = app.displayInfo.squareRatio;

            app.DisplayIterations.Value = app.displayInfo.dataSelection;

            for ii = 1:3
                lowBounds(ii) = app.displayInfo.limitsIfOverriden{ii}(1);
                highBounds(ii) = app.displayInfo.limitsIfOverriden{ii}(2);
                if app.displayInfo.incorporateOffset(ii) == "true"
                    lowBounds(ii) = lowBounds(ii) + app.displayInfo.offset(ii);
                    highBounds(ii) = highBounds(ii)+ app.displayInfo.offset(ii);
                end
                lowBounds(ii) = string(lowBounds(ii));
                highBounds(ii) = string(highBounds(ii));
            end
            overrideLimits = app.displayInfo.overrideLimits; 
            incOffset = app.displayInfo.incorporateOffset;
            offset = app.displayInfo.offset;
            app.PlotLimits.Data = [["x";"y";"color"] lowBounds' highBounds' overrideLimits' offset' incOffset'];

            if ~isempty(app.storedDataSettings)
                parameters = [app.storedDataSettings.scan.parameter]';
                stepsToView = [app.storedDataSettings.scan.stepToView]';
                app.ScanStepTable.Data = [parameters stepsToView];

                % if ~app.ex.randomizeScanPoints
                %     scanProgress = cell(1,app.storedDataSettings.nScans);
                %     for ii = 1:app.storedDataSettings.nScans
                %         scanProgress{ii} = sprintf('%s %d/%d',app.storedDataSettings.scan.parameter(ii),...
                %             app.storedDataSettings.completed(ii),app.storedDataSettings.scan.nSteps(ii));
                %     end
                % else
                %     scanProgress = {sprintf('%d/%d total steps',app.ex.odometer,...
                %         prod(app.storedDataSettings.scan.nSteps))};
                % end
                % app.ScanProgress.Value = scanProgress;
            end

            %Must be done individually because it would make a single string
            %rather than a string arrray
            colorLimits(1) = convertCharsToStrings(num2str(app.displayInfo.staticColorLimits(1)));
            colorLimits(2) = convertCharsToStrings(num2str(app.displayInfo.staticColorLimits(2)));

            app.StaticColorLimits.Data = [["minimum";"maximum"] colorLimits'];

            app.UseStaticColorLimits.Value = app.displayInfo.useStaticColorLimits;

            DisplayUpdate(app)

        end

        function DisplayUpdate(app)

            chooseVisiblePlot(app)
            if ~isempty(app.ex.data) && app.PulseSequencePlot.Visible == "off"
                %Plots 1D and 2D data if the plots are visible, otherwise
                %makes the data invisible
                scan = app.storedDataSettings.scan;

                %If there's only one scan present, presents an error when
                %trying to change which scan is displayed. This should be
                %changed to reference the data not the scan dimension.
                if numel(app.ex.scan) < 2 && app.yAxisScanNum.Value ~= 0 && app.xAxisScanNum.Value ~= 0
                    errordlg('Only one scan dimension present, displaying data on x axis','only one scan dimension')
                    app.yAxisScanNum.Value = 0;
                    app.xAxisScanNum.Value = 1;
                    chooseVisiblePlot(app)
                end
                %gives the data points that haven't been taken a value of 0
                %rather than being an empty vector
                data = app.ex.data.values;
                for ii = 1:length(data)
                    if isempty(data{ii,end})
                        data{ii,end} = [0,0];
                    end
                end

                %makes datacell variable with either signal, reference, or
                %contrast, which is used to create plots
                if strcmpi(app.ThingToPlot.Value,'contrast')
                    if strcmpi(app.ContrastType.Value,'ref - sig')
                        dataCell = cellfun(@(d) d(1)-d(2),data,'UniformOutput',false);
                    elseif strcmpi(app.ContrastType.Value,'(ref - sig)/ref')
                        dataCell = cellfun(@(d) (d(1)-d(2))/d(1),data,'UniformOutput',false);
                    elseif strcmpi(app.ContrastType.Value,'(ref - sig)/sig')
                        dataCell = cellfun(@(d) (d(1)-d(2))/d(2),data,'UniformOutput',false);
                    else %(ref-sig)/(ref+sig)
                        dataCell = cellfun(@(d) (d(1)-d(2))/(d(1)+d(2)),data,'UniformOutput',false);
                    end
                elseif strcmpi(app.ThingToPlot.Value,'reference')
                    dataCell = cellfun(@(d) d(1),data,'UniformOutput',false);
                elseif strcmpi(app.ThingToPlot.Value,'snr')
                        dataCell = cellfun(@(d) (d(1)-d(2))/d(1)*sqrt(d(1)),'UniformOutput',false);
                else %signal
                    dataCell = cellfun(@(d) d(2),data,'UniformOutput',false);
                end
                dataMatrix = cell2mat(dataCell);
                
                %creates three cells which are later used as indices when
                %reducing the data to a single vector or matrix to plot.
                %indices are based on the stepToView for each scan.
                scansViewing = [app.xAxisScanNum.Value, app.yAxisScanNum.Value];
                if min(scansViewing) ~= 0
                    stepsBeforeViewing = {scan(1:min(scansViewing)-1).stepToView};
                    stepsBetweenViewing = {scan(min(scansViewing)+1:max(scansViewing)-1).stepToView};
                else
                    stepsBeforeViewing = {};
                    stepsBetweenViewing = {scan(1:max(scansViewing) - 1).stepToView};
                end
                stepsAfterViewing = {scan(max(scansViewing)+1:end).stepToView};
                currentIteration = size(dataMatrix);
                currentIteration = currentIteration(end);

                
                if app.OneDPlot.Visible == "on"
                    %reduces the data down to a plotable vector or matrix.
                    if strcmpi(app.DisplayIterations.Value,'average')
                        reducedData = dataMatrix(stepsAfterViewing{:},:,stepsBetweenViewing{:},:);
                        if strcmpi(app.ThingToPlot.Value,'snr') && strcmpi(app.storedDataSettings.Value,'data analog')
                            reducedData = reducedData.*app.ex.data.nPoints(stepsAfterViewing{:},:,stepsBetweenViewing{:},:);
                        end
                        reducedData(reducedData==0) = NaN;
                        reducedData = mean(reducedData,numel(size(dataMatrix)),'omitnan');
                        reducedData(isnan(reducedData)) = 0;
                    elseif strcmpi(app.DisplayIterations.Value,'prior') && any(app.ex.data.iteration >=2)
                        reducedData = dataMatrix(stepsAfterViewing{:},:,stepsBetweenViewing{:},1:currentIteration-1);
                        if strcmpi(app.ThingToPlot.Value,'snr') && strcmpi(app.storedDataSettings.Value,'data analog')
                            reducedData = reducedData.*app.ex.data.nPoints(stepsAfterViewing{:},:,stepsBetweenViewing{:},1:currentIteration-1);
                        end
                        reducedData(reducedData==0) = NaN;
                        reducedData = mean(reducedData,numel(size(dataMatrix)),'omitnan');
                        reducedData(isnan(reducedData)) = 0;
                    else
                        reducedData = dataMatrix(stepsAfterViewing{:},:,stepsBetweenViewing{:},currentIteration);
                        if strcmpi(app.ThingToPlot.Value,'snr') && strcmpi(app.storedDataSettings.Value,'data analog')
                            reducedData = reducedData.*app.ex.data.nPoints(stepsAfterViewing{:},:,stepsBetweenViewing{:},currentIteration);
                        end
                        reducedData(isnan(reducedData)) = 0; 
                    end

                    if app.PulseNumToPlot.Value > numel(scan(max(scansViewing)).bounds)
                        warndlg('Chosen pulse number is greater than number of pulses being scanned, plotting pulse 1','Cannot Plot Chosen Pulse Number')
                        app.PulseNumToPlot.Value = 1;
                    end
                    scanBounds = scan(max(scansViewing)).bounds{app.PulseNumToPlot.Value};

                    steps = scan(max(scansViewing)).nSteps;
                    scanAxis = linspace(scanBounds(1),scanBounds(2),steps);
                    if any(reducedData == 0) 
                        baselineValue = 0.9*min(reducedData(reducedData ~= 0));
                        reducedData(reducedData == 0) = baselineValue;                    
                    end


                    if isempty(app.displayInfo.oneDPlot)
                        if app.xAxisScanNum.Value ~= 0
                            app.displayInfo.oneDPlot = plot(app.OneDPlot,scanAxis,reducedData);                            
                        else %plots with data on x axis
                            app.displayInfo.oneDPlot = plot(app.OneDPlot,reducedData,scanAxis);                     
                        end

                    else

                        if app.xAxisScanNum.Value ~= 0
                            app.displayInfo.oneDPlot.XData = scanAxis;
                            app.displayInfo.oneDPlot.YData = reducedData;
                        else
                            app.displayInfo.oneDPlot.XData = reducedData;
                            app.displayInfo.oneDPlot.YData = scanAxis;
                        end
                    end

                    app.displayInfo.oneDPlot.Visible = "on"; %makes data visible

                    AdjustPlotLimits(app) %adjusts the axes of the plot based on offset etc.

                elseif app.twoDPlot.Visible == "on"
                    if strcmpi(app.DisplayIterations.Value,'average')
                        reducedData = dataMatrix({stepsAfterViewing},:,{stepsBetweenViewing},:,{stepsBeforeViewing},:);
                        reducedData = mean(reducedData,numel(size(dataMatrix)));
                    elseif strcmpi(app.DisplayIterations.Value,'prior')
                        reducedData = dataMatrix({stepsAfterViewing},:,{stepsBetweenViewing},:,{stepsBeforeViewing},1:end-1);
                        reducedData = mean(reducedData,numel(size(dataMatrix)));
                    else
                        reducedData = dataMatrix({stepsAfterViewing},:,{stepsBetweenViewing},:,{stepsBeforeViewing},end);
                    end
                    
                    if app.PulseNumToPlot.Value > numel(scan(max(app.xAxisScanNum.Value).bounds))
                        warndlg('Chosen pulse number is greater than number of pulses being scanned, plotting pulse 1','Cannot Plot Chosen Pulse Number')
                        app.PulseNumToPlot.Value = 1;
                    end
                    if app.xAxisScanNum.Value > app.yAxisScanNum.Value
                        reducedData = reducedData';
                    end

                    xScanBounds = scan(app.xAxisScanNum.Value).bounds{app.PulseNumToPlot.Value};
                    xSteps = scan(app.xAxisScanNum.Value).nSteps;
                    xAxis = linspace(xScanBounds(1),xScanBounds(2),xSteps);

                    yScanBounds = scan(app.yAxisScanNum.Value).bounds{app.PulseNumToPlot.Value};
                    ySteps = scan(app.yAxisScanNum.Value).nSteps;
                    yAxis = linspace(yScanBounds(1),yScanBounds(2),ySteps);
                    
                    if numel(reducedData) ~= xSteps*ySteps
                        baselineValue = 0.9*min(reducedData);
                        plottedData = zeros(ySteps,xSteps) +baselineValue;
                        plottedData(1:length(reducedData),1:height(reducedData)) = reducedData;                       
                    else
                        plottedData = reducedData;
                    end
                    app.displayInfo.twoDPlot = imagesc(app.TwoDPlot,xAxis,yAxis,plottedData);
                    colorbar


                end
            else
                %only turns the visible property off if the plots are not a
                %double. They are only double before anything has been
                %plotted.
                if ~isa(app.displayInfo.oneDPlot,'double')
                    app.displayInfo.oneDPlot.Visible = "off";
                end
                if ~isa(app.displayInfo.twoDPlot,'double')
                    app.displayInfo.twoDPlot.Visible = "off";
                end
            end

            %generated pulse sequence plot if its visible and there is a
            %sequence
            if app.PulseSequencePlot.Visible == "on" && (~isempty(app.ex.pulseBlaster)||~isempty(app.ex.pulseBlaster.userSequence))
                %creates plottable data from binary
                seq = app.ex.pulseBlaster.userSequence;
                xData = [];
                yData = [];
                for ii = 1:numel(seq)
                    binChannels = seq(ii).channelsBinary;
                    binChannels = binChannels(~isspace(binChannels));
                    if ii == 1
                        xData = 0;
                    else
                        xData = [xData,xData(end)+seq(ii-1).duration];
                    end
                        for jj = 1:numel(binChannels)
                            yData(ii,jj) = str2double(binChannels(jj))+(jj-1)*2+.5; %#ok<*SAGROW>
                        end               
                end
                %adds last point in sequence
                xData = [xData,xData(end)+seq(end).duration];
                for jj = 1:numel(binChannels)
                       yData(numel(seq)+1,jj) = str2double(binChannels(jj))+(jj-1)*2+.5; %#ok<*SAGROW>
                end
                %If there is no plot, makes a new plot and adjusts the axes labels. Otherwise just
                %updates the data
                if isempty(app.displayInfo.pulsePlot)
                    app.displayInfo.pulsePlot = stairs(app.PulseSequencePlot,xData,yData);
                    
                else
                    for ii = 1:numel(binChannels)
                        app.displayInfo.pulsePlot(ii).XData = xData;
                        app.displayInfo.pulsePlot(ii).YData = yData(:,ii);
                        app.displayInfo.pulsePlot(ii).Visible = "on";
                    end
                end
                %List of colors for pulse sequence line, in order:
                %green, blue, orange,purple,yellow,red
                colorList = [[0.4660, 0.6740, 0.1880]; [0, 0.4470, 0.7410];[0.8500, 0.3250, 0.0980];[0.4940, 0.1840, 0.5560];[0.9290, 0.6940, 0.1250];[0.6350, 0.0780, 0.1840]];
                for ii = 1:6
                    app.displayInfo.pulsePlot(end-ii+1).Color = colorList(ii,:);
                end
                for ii = 1:numel(binChannels)
                    app.displayInfo.pulsePlot(ii).LineWidth = 1;
                end
                app.PulseSequencePlot.YTick = [1:numel(binChannels)].*2 -1; %#ok<NBRAK1>
                yLabels = [];
                for ii = 1:numel(binChannels)
                    yLabelList = ["AOM","Data","Signal","RF","I","Q","windfreak RF"];
                    yLabels = [yLabels,yLabelList(ii)];
                end
                app.PulseSequencePlot.YTickLabel = fliplr(yLabels);
                app.PulseSequencePlot.XLim = [0 max(app.displayInfo.pulsePlot(1).XData)];

            else %if the pulse plot is not visible, makes the pulse data invisible
                for ii = 1:numel(app.displayInfo.pulsePlot)
                    app.displayInfo.pulsePlot(ii).Visible = "off";
                end
            end

            UpdatePlotlabel(app)

        end

        function ScanUpdate(app)
            app.RandomizescanCheckBox.Value = app.ex.randomizeScanPoints;

            if ~isempty(app.ex.scan(1).parameter)
               tableType = [app.ex.scan.parameter]';
               tablenSteps = [app.ex.scan.nSteps]';
               tableNotes = [app.ex.scan.notes]';
               for ii = 1:numel(app.ex.scan)
                   tablePulse(ii) = strjoin(string(app.ex.scan(ii).address), ',');
                   tableStepSize(ii) = strjoin(string(app.ex.scan(ii).stepSize), ',');
                   for jj = 1:numel(app.ex.scan(ii).bounds)
                       scanStarts(jj) = string(app.ex.scan(ii).bounds{jj}(1));
                       scanEnds(jj) = string(app.ex.scan(ii).bounds{jj}(2));
                   end
                   tableStart(ii) = strjoin(scanStarts,',');
                   tableEnd(ii) = strjoin(scanEnds,',');
               end

               app.ScanningTable.Data = [tableType tableStart' tableEnd' tablenSteps tableStepSize', tablePulse',tableNotes];
            end
            
            if ~isempty(app.ex.DAQ)
                app.DataType.Value = app.ex.DAQ.activeDataChannel;
            end
        end

        function StatusUpdate(app)
           switch app.scanStatus
              case 'running'
                 app.StatusLamp.Color = [0,1,0]; %green
              case 'reset'
                 app.StatusLamp.Color = [0,0,1]; %dark blue
              case 'aborted'
                 app.StatusLamp.Color = [1,0,0]; %red
              case 'aborting'
                 app.StatusLamp.Color = [1,1,0]; %yellow
              case 'completed'
                 app.StatusLamp.Color = [0,1,1]; %cyan
           end
        end

        function errorPresent = ErrorCheck(app)
            scan = app.ex.scan; %shorthand
            errorPresent = true;

            if ~app.ex.PIstage.connected
                loadinstruments = uiconfirm(app.UIFigure,'Stage is not currently loaded. Would you like to load it or cancel measurement?',...
                    'Instruments unloaded','Icon','warning','Options',{'Load Stage','Cancel'});
                switch loadinstruments
                    case 'Load Stage'
                        stageInitialization
                        appUpdate(app)
                    case 'Cancel'
                        return
                end
            end

            if ~strcmp(app.scanStatus,'reset')
                loadinstruments = uiconfirm(app.UIFigure,'This will resume previous run adding on to old data. Proceed?',...
                    'Not Reset','Icon','warning','Options',{'Proceed','Cancel'});
                if strcmp(loadinstruments,'Cancel')
                    return
                end
            end

            %          if  ~strcmp(app.Warnings.Value{1},'No warnings present')
            %             disregard = uiconfirm(app.UIFigure,'Continue despite warnings?','Warnings present','Icon','warning');
            %             if strcmp(disregard,'Cancel')
            %                return
            %             end
            %          end

            if length(scan.start) == 1 && strcmp(scan.parameter,"Stage X") && scan.start == 1 && scan.end == 10 && scan.nSteps == 10 && scan.stepSize == 1
                errordlg('Scan has not been changed from default. Go to scan tab to change','Default Scan')
                return
            end

            boxDir = dir(strcat(app.BoxAddress.Value,'\Backlund lab shared\code\Function Library'));
            if isempty(boxDir)
                errordlg('Current box address invalid. Go to General tab to fix','Invalid box address')
                return
            end

            [~,uniqueIndices] = unique(scan.parameter);
            if numel(scan.parameter) ~= numel(uniqueIndices)
                errordlg('Multiple scans with identical types detected. Go to scan tab to change','Replicate Scans')
                return
            end

            scanStages = convertStringsToChars(scan.parameter);
            if isa(scanStages,'cell')
                scanStages = convertStringsToChars(scan.parameter);
                scanStages = cellfun(@(x) x(end),scanStages,'UniformOutput',false);
            else
                scanStages = convertStringsToChars(scan.parameter);
                scanStages = scanStages(end);
            end
            if any(ismember(scanStages,app.ex.optimizationInfo.stageAxes)) && app.StageAutoOptimization.Value
                proceedExperiment = uiconfirm(app.UIFigure,['Auto-optimization on while attempting stage scan of the same axis.' ...
                    'This is not recommended as the optimization process can affect the scan. Proceed anyways?'],...
                    'Incompatible Settings','Icon','warning','Options',{'Proceed','Cancel'});
                if strcmp(proceedExperiment,'Cancel')
                    return
                end
            end

            if  app.stageControl.ignoreWait && app.StageAutoOptimization.Value
                proceedExperiment = uiconfirm(app.UIFigure,['Auto-optimization on while ignore wait is also on.' ...
                    'This is not recommended as it skews the optimization process. Proceed anyways?'],...
                    'Incompatible Settings','Icon','warning','Options',{'Proceed','Cancel'});
                if strcmp(proceedExperiment,'Cancel')
                    return
                end
            end

            if strcmp(app.scanStatus,'running')
                errordlg('Cannot run experiment while optimization is running','Cannot Run Experiment')
                return
            end

            %% Warnings

            %If it reaches the end without triggering an error, set the output
            %to false
            errorPresent = false;
        end

        function newStructure = ModifyAllFields(~,oldStructure,fieldsToChange,fieldValues,address)
            %This function replaces holding all these settings in a variable
            %then changing them all at once to the structure. Instead it
            %changes each substructure one at a time
            %Honestly I don't think I would do it this way again if I had to
            %go back and redo it

            %It is necessary to input the fields to change not because matlab
            %cannot identify what fields are there, but because it needs to
            %match the field name to the new value to be input

            %An input of {'delete'} for the first cell in fieldValues input
            %will cause the function to delete the values of the address given

            if strcmp(fieldValues{1},'delete')
                oldStructure(address) = [];
                newStructure = oldStructure;
            else
                newStructure = struct2cell(oldStructure);%Create new structure based of the old
                namesList = fieldnames(oldStructure);%Create list of field names in order


                for ii = 1:numel(fieldsToChange)
                    %Find where the field name of the structure is the field name
                    %that has been input
                    cellNumber = find(strcmp(fieldsToChange(ii),namesList));
                    if isempty(cellNumber)
                        error('Invalid field name. Name must be one of the fields of the structure input')
                    end
                    %Take the old structure, replace the value based on given
                    %address, then add it back into the new structure


                    newValue = newStructure(cellNumber,1:numel(oldStructure));
                    if address <= numel(newValue)
                        newValue(address+1:end+1) = newValue(address:end);
                    end
                    newValue{address} = fieldValues{ii};
                    %Converts cell back to structure using the names found from the
                    %input structure
                    newStructure(cellNumber,1:numel(oldStructure)+1) = newValue;
                end
                newStructure = cell2struct(newStructure,namesList,1);
                % gets rid of an empty row of information that is present after
                %adding the first scan
                if isempty(getfield(newStructure(end), namesList{1})) %#ok<GFLD>
                    newStructure(end) = [];
                end
            end

        end

        function newRootName = CheckFolder(~,oldRootName,folderName)
            %Updates what the new root name will be
            newRootName = strcat(oldRootName,'\',folderName);

            %Load the directory
            fileList = struct2cell(dir(oldRootName));
            fileList = fileList(1,:);%Only name row

            %Makes a new folder if one doesn't already exist
            fileExists = any(strcmp(folderName,fileList),"all");
            if ~fileExists
                mkdir(oldRootName, folderName)
            end

        end

        function DataSave(app,rootSaveName)
            %Should be a single variable not part of a structure when saved
            data = app.ex.data;

            %Saves the data in a format readable by MatLab
                mSaveName = strcat(rootSaveName,'\', 'MatLab_Data.mat');
                save(mSaveName,"data")
                dataSettings = app.storedDataSettings;
                mSaveName = strcat(rootSaveName,'\', 'MatLab_Data_Settings.mat');
                save(mSaveName,"dataSettings")
                userNotes = app.SavedUserNotes.Value;
                mSaveName = strcat(rootSaveName,'\', 'MatLab_User_Notes.mat');
                save(mSaveName,"userNotes")

            %If saving as a tif is not selected, end the function here. I did
            %this instead of putting everything below in one massive if
            %statement because it makes code formatting nicer in my opinion
                uialert(app.UIFigure,'Data Saved!','Save Successful','Icon','success')

        end

        function SetupSave(app,rootSaveName)

            setupSettings = [];
            setupSettings.general = [];
            setupSettings.instruments = [];

             %StoredSetupSettings is dividied into general and insturments.
             %This is done beacuse the properites of the instruments need
             %to be loaded differnetly than the properties of the app
            if ~isempty(app.ex)
                generalCopy = {'scan','optimizationInfo','randomizeScanPoints','useManualSteps','manualSteps'};
                for ii = 1:numel(generalCopy)
                    setupSettings.general.(generalCopy{ii}) = app.ex.(generalCopy{ii});
                end

                if ~isempty(app.ex.pulseBlaster)
                    pulseCopy = {'userSequence','useTotalLoop','nTotalLoops'};
                    for ii = 1:numel(pulseCopy)
                        setupSettings.instruments.pulseBlaster.(pulseCopy{ii}) = app.ex.pulseBlaster.(pulseCopy{ii});
                    end
                end

                if ~isempty(app.ex.PIstage)
                    stageCopy = {'tolerance','toleranceStandardDeviations','checkTolerance','pauseTime','absoluteTolerance'};
                    for ii = 1:numel(stageCopy)
                        setupSettings.instruments.PIstage.(stageCopy{ii}) = app.ex.PIstage.(stageCopy{ii});
                    end
                end

                if ~isempty(app.ex.DAQ)
                    DAQCopy = {'differentiateSignal','activeDataChannel'};
                    for ii = 1:numel(DAQCopy)
                        setupSettings.instruments.DAQ.(DAQCopy{ii}) = app.ex.DAQ.(DAQCopy{ii});
                    end
                end

                if ~isempty(app.ex.SRS_RF)
                    SRSCopy = {'enabled','frequency','amplitude','modulationEnabled','modulationType','modulationWaveform'};
                    for ii = 1:numel(SRSCopy)
                        setupSettings.instruments.SRS_RF.(SRSCopy{ii}) = app.ex.SRS_RF.(SRSCopy{ii});
                    end
                end

                if ~isempty(app.ex.windfreak_RF)
                    windfreakCopy = {'enabled','frequency','amplitude','modulationEnabled','modulationType','modulationWaveform'};
                    for ii = 1:numel(windfreakCopy)
                        setupSettings.instruments.windfreak_RF.(windfreakCopy{ii}) = app.ex.windfreak_RF.(windfreakCopy{ii});
                    end
                end
               
                mSaveName = strcat(rootSaveName,'\', 'MatLab_Setup_Settings.mat');
                save(mSaveName,"setupSettings")
                userNotes = app.SavedUserNotes.Value;
                mSaveName = strcat(rootSaveName,'\', 'MatLab_User_Notes.mat');
                save(mSaveName,"userNotes")
                uialert(app.UIFigure,'Setup Saved!','Save Successful','Icon','success')
            end
        end
        
        function LoadPulseSequence(app, newSequence)
            if ~isempty(app.ex.pulseBlaster.userSequence)
                for ii = 1:numel(app.ex.pulseBlaster.userSequence)
                    app.ex.pulseBlaster = deletePulse(app.ex.pulseBlaster,1);
                end
            end
            for ii = 1:numel(newSequence)
                pulseInfo = [];
                pulseInfo.numericalOutput = newSequence(ii).numericalOutput;
                pulseInfo.duration = newSequence(ii).duration;
                pulseInfo.directionType = newSequence(ii).directionType;
                pulseInfo.contextInfo = newSequence(ii).contextInfo;
                pulseInfo.notes = newSequence(ii).notes;
                app.ex.pulseBlaster = addPulse(app.ex.pulseBlaster,pulseInfo,ii);
            end

        end

        function LoadSavedSettings(app, instrument)
            %If there is stored settings for a given instrument due to
            %loading a previous setup, replace the default value with the
            %loaded value
            if isfield(app.storedSetupSettings,instrument)
                newPropNames = fieldnames(app.storedSetupSettings.(instrument));
                for ii = 1:numel(newPropNames)
                    if c2s(newPropNames{ii} == "userSequence")
                        LoadPulseSequence(app,app.storedSetupSettings.pulseBlaster.userSequence)
                    else
                        app.ex.(instrument).(newPropNames{ii}) = app.storedSetupSettings.(instrument).(newPropNames{ii});
                    end
                end
            end
        end

        function instrumentOn = checkInstrumentStatus(app,propertyName)

            if isempty(app.ex.(propertyName)) || ~app.ex.(propertyName).connected
                instrumentOn = false;
                fprintf('%s not connected\n',propertyName)
            else
                instrumentOn = true;
            end
        end
        
        function abortScan(app)
            %necessary for scans lasting for a specified time. There might
            %be a better way to do this.
            app.scanStatus = 'aborted';
        end
        
        function chooseVisiblePlot(app)
            %decides which plot should be visible based on the dimensions
            %of the data, whats being plotted, and the x/y axis scan
            %numbers
            if strcmpi(app.ThingToPlot.Value, 'pulsesequence')
                if isempty(app.ex.pulseBlaster) || isempty(app.ex.pulseBlaster.userSequence)
                    return
                else
                app.TwoDPlot.Visible = false;
                app.OneDPlot.Visible = false;
                app.PulseSequencePlot.Visible = true;
                end
            else
                if isempty(app.ex.data)
                    return
                elseif app.xAxisScanNum.Value == 0 || app.yAxisScanNum.Value ==0
                    app.TwoDPlot.Visible = false;
                    app.OneDPlot.Visible = true;
                    app.PulseSequencePlot.Visible = false;
                else
                    app.TwoDPlot.Visible = true;
                    app.OneDPlot.Visible = false;
                    app.PulseSequencePlot.Visible = false;
                end
            end            
        end

        function AdjustPlotLimits(app)
            %adjusts the limits and the offset of the plot based on the
            %settings of the table on top of the output tab
            if~isempty(app.ex.data)
                if app.OneDPlot.Visible == "on"

                    if app.xAxisScanNum.Value ~= 0
                        dataRange = max(app.displayInfo.oneDPlot.YData) - min(app.displayInfo.oneDPlot.YData);
                        defaultLimits = [min(app.displayInfo.oneDPlot.XData), max(app.displayInfo.oneDPlot.XData);
                            min(app.displayInfo.oneDPlot.YData) - 0.1*dataRange, max(app.displayInfo.oneDPlot.YData)+0.1*dataRange];
                        if defaultLimits(2,1) == 0 && defaultLimits(2,2) == 0
                            app.defaultLimits(2,2) = 1;
                        end
                    else %data on x axis
                        dataRange = max(app.displayInfo.oneDPlot.XData) - min(app.displayInfo.oneDPlot.XData);
                        defaultLimits = [min(app.displayInfo.oneDPlot.XData) - 0.1*dataRange, max(app.displayInfo.oneDPlot.XData)+0.1*dataRange;
                            min(app.displayInfo.oneDPlot.YData), max(app.displayInfo.oneDPlot.YData)];
                    end


                    if strcmpi(app.displayInfo.overrideLimits{1},"true")
                        app.OneDPlot.XLim = app.displayInfo.limitsIfOverriden{1};
                    else
                        app.OneDPlot.XLim = [defaultLimits(1,1),defaultLimits(1,2)];
                    end

                    if strcmpi(app.displayInfo.overrideLimits{2},"true")
                        app.OneDPlot.YLim = app.displayInfo.limitsIfOverriden{2};
                    else
                        app.OneDPlot.YLim = [defaultLimits(2,1),defaultLimits(2,2)];
                    end
                    if strcmpi(app.displayInfo.incorporateOffset(1),"true")
                        for ii = 1:numel(app.OneDPlot.XTick)
                            app.OneDPlot.XTickLabels{ii} = num2str(app.OneDPlot.XTick(ii) + app.displayInfo.offset(1));
                        end
                    else
                        app.OneDPlot.XTickLabelMode = 'auto';
                    end

                    if strcmpi(app.displayInfo.incorporateOffset(2),"true")
                        for ii = 1:numel(app.OneDPlot.YTick)
                            app.OneDPlot.YTickLabels{ii} = num2str(app.OneDPlot.YTick(ii) + app.displayInfo.offset(2));
                        end
                    else
                        app.OneDPlot.YTickLabelMode = 'auto';
                    end

                elseif app.twoDPlot.Visible == "on"
                end
            end
        end
        
        function UpdatePlotlabel(app)
            if app.OneDPlot.Visible == "on"
                if app.xAxisScanNum.Value ~= 0                   
                    parameter = app.storedDataSettings.scan(app.xAxisScanNum.Value).parameter;                    
                    scanAxis = 'XLabel';
                    dataAxis = 'YLabel';
                    app.OneDPlot.Title.String = app.storedDataSettings.scan(app.xAxisScanNum.Value).notes;
                else
                    parameter = app.storedDataSettings.scan(app.yAxisScanNum.Value).parameter;
                    scanAxis = 'YLabel';
                    dataAxis = 'XLabel';
                    app.OneDPlot.Title.String = app.storedDataSettings.scan(app.yAxisScanNum.Value).notes;
                end

                if numel(app.OneDPlot.Title.String) > 75
                    app.OneDPlot.Title.HorizontalAlignment = 'left';
                    app.OneDPlot.Title.Position = [1.6 5.1872e-04 0];
                end

                parameter = strcat(' ',parameter);
                if any(strcmpi(parameter,'x'))
                    app.OneDPlot.(scanAxis).String = 'Stage X (μm)';
                elseif any(strcmpi(parameter,'y'))
                    app.OneDPlot.(scanAxis).String = 'Stage Y (μm)';
                elseif any(strcmpi(parameter,'z'))
                    app.OneDPlot.(scanAxis).String = 'Stage Z (μm)';
                elseif strcmpi(parameter,'frequency')
                    app.OneDPlot.(scanAxis).String = 'RF Frequency (GHz)';
                elseif strcmpi(parameter,'amplitude')
                    app.OneDPlot.(scanAxis).String = 'RF Amplitude (dBm)';
                elseif strcmpi(parameter,'duration')
                    app.OneDPlot.(scanAxis).String = 'Pulse Duration (ns)';
                end
                if ~strcmpi(app.ThingToPlot.Value,'pulsesequence')
                    app.OneDPlot.(dataAxis).String = app.ThingToPlot.Value;
                end
                
            end

        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFunction(app)
            %Creates experiment object
            
            app.StageConfig.Value = 'PI_stage';
            app.PulseBlasterConfig.Value = 'pulse_blaster_default';
            app.DAQConfig.Value = 'daq_6361';
            app.SRSRFConfig.Value = 'SRS_RF';
            app.WindfreakRFConfig.Value = 'windfreak_RF';
            %app.SproutLaserConfig.Value = 'weneedaconfig'

            app.UserName.Items = app.userList;
            app.UserName2.Items = app.userList;
            app.UIFigure.Units = "normalized";
           

            app.SaveFolderName.Value = [];
            % app.BoxAddress.Value = [];
            app.UserName.Value = 'Common';
            app.SavedUserNotes.Value = '';
            % app.DataSetupsSwitch.Value = 'Data';%Removed, set in design view**
            app.StageAddress.Value = double(1);

            app.ex.scan.completed = [];
            app.ex.scan.address = [];
            app.ex.scan.nSteps = [];
            app.ex.scan.parameter = [];
            app.ex.scan.bounds = [];
            app.ex.scan.stepSize = [];
            app.ex.scan.identifier = [];
            app.ex.scan.stepToView = [];
            app.ex.scan.notes = [];
            app.ex.randomizeScanPoints = 0;

            app.storedDataSettings = [];

            app.storedSetupSettings = [];

            app.performScanReset = true;
   
            app.displayInfo.squareRatio = 0;
            app.displayInfo.dataSelection = 'Average';
            app.displayInfo.limitsIfOverriden = {[1,100];[1,100];[1,100]};
            app.displayInfo.overrideLimits = {"false","false","false"};
            app.displayInfo.staticColorLimits = [0 1];
            app.displayInfo.useStaticColorLimits = 0;
            app.displayInfo.offset = [0,0,0];
            app.displayInfo.incorporateOffset = ["false","false","false"];
            app.Numberofmanualoptimizations.Value = 1;

            app.displayInfo.pulsePlot = [];
            app.displayInfo.oneDPlot = [];
            app.displayInfo.twoDPlot = [];

            AppUpdate(app)

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if isempty(app.ex.pulseBlaster)||isempty(app.ex.pulseBlaster.userSequence)
                errordlg('No pulse sequence selected','No Pulse Sequence')
                return
            end

            %Prevents multiple starts**
            if strcmpi(app.scanStatus,'running')
               return
            end
            

            %Revamped this bit. Now properly resets data and scan**
            startIteration = 1;
            continueScan = false;
            if  any(strcmpi(app.scanStatus,{'aborting','aborted','completed'}))
                answer = questdlg('Erase old data or average with new data?','Data present','Erase old data','Average with new data','Cancel','Cancel');
                if strcmpi(answer,'cancel')
                    return
                elseif strcmpi(answer,'erase old data')
                    app.ex = resetAllData(app.ex,[0,0]);
                else
                    startIteration = min(app.ex.data.iteration)+1;
                    if ~all(cell2mat(app.ex.odometer) == [app.ex.scan.nSteps])
                        continueScan = true;
                    end
                end
            else                
                app.ex = resetAllData(app.ex,[0,0]);
            end

            app.scanStatus = 'running';
            StatusUpdate(app)
            app.storedDataSettings.scan = app.ex.scan;
            app.storedDataSettings.dataType = app.ex.DAQ.activeDataChannel;
            %**These should be set elsewhere so they can be saved and
            %loaded properly
            % if strcmpi(app.LoopsOrDuration.Value,'# of Loops')
                % app.ex.pulseBlaster.nTotalLoops = app.TotalLoops.Value;
            % else 
            %     if any(strcmpi(app.storedDataSettings.scan.parameter,'duration'))
            %     end
            % end
            app.ex.pulseBlaster.useTotalLoop = true;
            if strcmpi(app.IterationsType.Value,'number') 
                iterations = app.MaxIterations.Value;               
            elseif strcmpi(app.IterationsType.Value,'time (min)')
                collectionTimer = timer;
                collectionTimer.StartDelay = 60*app.MaxIterations.Value;
                collectionTimer.TimerFcn = abortScan(app);
                iterations = Inf;
            else
                %if iterations is set to perpetual,uses one as a default
                %value. The for while
                %will keep repeaing until the abort button is stopped
                iterations = Inf; 
            end


            for ii = startIteration:iterations

                %Resets scan unless continuing previous**
                if ~continueScan
                    app.ex = resetScan(app.ex);
                end

                while ~all(cell2mat(app.ex.odometer) == [app.ex.scan.nSteps])
                    [app.ex,doOptimization] = checkOptimization(app.ex);
                    if doOptimization
                        app.ex = stageOptimization(app.ex);
                        uialert(app.UIFigure,'Optimization Complete!','Success','Icon','success')
                    end
                    app.ex = takeNextDataPoint(app.ex,'pulse sequence');
                    app.ex = subtractBaseline(app.ex,app.BaselineSubtraction.Value);
                    DisplayUpdate(app)
                    %Needs aborted comparison as well to prevent errors
                    %when user clicks abort too many times**
                    if any(strcmpi(app.scanStatus,{'aborted','aborting'}))
                        app.scanStatus = 'aborted';
                        app.performScanReset = false;
                        StatusUpdate(app)
                        return
                    end
                end
            end

            app.scanStatus = 'completed';
            StatusUpdate(app) 
            

        end

        % Button pushed function: AbortButton
        function AbortButtonPushed(app, event)
            %Also makes it continue aborting if already aborting**
            if strcmpi(app.scanStatus,'running') || strcmpi(app.scanStatus,'aborting')
                app.scanStatus = 'aborting';
            else
                app.scanStatus = 'aborted';
            end
            AppUpdate(app)
        end

        % Button pushed function: StageUnload
        function StageUnloadButtonPushed(app, event)
            %Unloads stage by running CleanUp function then updates the app
            disconnect(app.ex.PIstage);
            AppUpdate(app)
        end

        % Button pushed function: RefreshAppButton
        function RefreshAppButtonPushed(app, event)
            %Refreshes all panels of the app
            AppUpdate(app)
        end

        % Cell edit callback: ScanningTable
        function ScanningTableCellEdit(app, event)
            if strcmpi(app.scanStatus,'running')
                errordlg('Cannot edit scan while running, abort scan to continue','Scan Running')
                return
            end
            rowNum = event.Indices(1);
            newData = event.NewData;
            switch event.Indices(2)
                case 1 %type
                    switch lower(newData)
                        case {'stage x','x'}
                            app.ex.scan(rowNum).identifier = 'stage';
                            app.ex.scan(rowNum).parameter = "x";
                        case {'stage y','y'}
                            app.ex.scan(rowNum).identifier = 'stage';
                            app.ex.scan(rowNum).parameter = "y";
                        case {'stage z','z'}
                            app.ex.scan(rowNum).identifier = 'stage';
                            app.ex.scan(rowNum).parameter = "z";
                        case {'rf','rf frequency','frequency','f','freq','odmr'}
                            %**Temporarily changed to SRS_RF. Should be a
                            %parameter than can be edited
                            app.ex.scan(rowNum).identifier = 'SRS_RF';
                            app.ex.scan(rowNum).parameter = "frequency";
                        case {'rf amplitude','amplitude','amp'}
                            app.ex.scan(rowNum).identifier = 'RF_generator';
                            app.ex.scan(rowNum).parameter = "amplitude";
                        case {'pulse blaster','pb','pulse','duration','pulse duration','dur'}
                            app.ex.scan(rowNum).identifier = 'Pulse Blaster';
                            app.ex.scan(rowNum).parameter = "duration";
                        case {'blank','nothing''dummy','empty','none','na'}
                            app.ex.scan(rowNum).parameter = 'blank';
                            app.ex.scan(rowNum).identifier = 'blank';
                    end

                case {2,3}
                    %start or end
                    %TO DO: ensure scans where start is less than end work
                    %properly
                    if event.Indices(2) == 2
                        scanStarts = split(c2s(newData),[", ",","," "]);
                        scanStarts = scanStarts(scanStarts ~= "");
                        for ii = 1:numel(scanStarts)
                            app.ex.scan(rowNum).bounds{ii}(1) = str2double(scanStarts(ii));
                        end
                    else
                        scanEnds = split(c2s(newData),[", ",","," "]);
                        scanEnds = scanEnds(scanEnds ~= "");
                        for ii = 1:numel(scanEnds)
                            app.ex.scan(rowNum).bounds{ii}(2) = str2double(scanEnds(ii));
                        end
                    end
                    if strcmpi(app.DefaultScanChange.Value,'Step size') || numel(app.ex.scan(rowNum).address) > 1
                        %Change step size while keeping number of steps constant
                        for ii = 1:numel(app.ex.scan(rowNum).bounds)
                            app.ex.scan(rowNum).stepSize(ii) = (app.ex.scan(rowNum).bounds{ii}(2) - app.ex.scan(rowNum).bounds{ii}(1))./(app.ex.scan(rowNum).nSteps-1);
                        end
                    else
                        %Change number of steps while keeping step size as close
                        %as possible to the original

                        scanStart = app.ex.scan(rowNum).bounds{1}(1);
                        scanEnd = app.ex.scan(rowNum).bounds{1}(2);
                        stepChecker = (scanEnd - scanStart) ./ app.ex.scan(rowNum).stepSize + 1;

                        if stepChecker <= 0
                            errordlg('Number of steps became negative, changing step size instead')
                            app.ex.scan(rowNum).stepSize = (scanEnd - scanStart) ./ (app.ex.scan(rowNum).nSteps - 1);
                        elseif stepChecker == floor(stepChecker)
                            app.ex.scan(rowNum).nSteps = stepChecker;
                        else
                            stepChecker = round(stepChecker);
                            app.ex.scan(rowNum).nSteps = stepChecker;
                            app.ex.scan(rowNum).stepSize = (scanEnd - scanStart) ./ (app.ex.scan(rowNum).nSteps-1);
                            changeWarning = sprintf('Step size changed to %.4g to allow for whole number of steps',app.ex.scan(rowNum).stepSize);
                            warndlg(changeWarning,'Altered Step Size')
                        end

                    end

                case 4 % # of steps
                    if str2double(newData) == floor(str2double(newData)) && str2double(newData) > 0
                        app.ex.scan(rowNum).nSteps = str2double(newData);
                    else
                        errordlg('Number of steps must be a whole number','Invalid # of Steps')
                    end
                    for ii = 1:numel(app.ex.scan(rowNum).bounds)
                        app.ex.scan(rowNum).stepSize(ii) = (app.ex.scan(rowNum).bounds{ii}(2) - app.ex.scan(rowNum).bounds{ii}(1)) ./ (app.ex.scan(rowNum).nSteps-1);
                    end
                case 5 %step size
                    scanSteps = split(c2s(newData),[", ",","," "]);
                    scanSteps = str2double(scanSteps(scanSteps ~= ""));
                    addressNum = numel(app.ex.scan(rowNum).address);
                    if numel(scanSteps) > addressNum
                        warndlg('Number of step sizes is greater than the number of addresses being scanned, deleting last items in list','Too Many Step Sizes');
                    end
                    if numel(scanSteps) == 1 %#ok<*ISCL> %adujusts number of steps when the step size is changed
                        stepChecker = (app.ex.scan(rowNum).bounds{1}(2) - app.ex.scan(rowNum).bounds{1}(1)) ./ str2double(newData) + 1;
                        if  stepChecker <= 0
                            errordlg('Number of steps must be a whole number, step size must have corresponding sign','Invalid Step Size')
                        elseif stepChecker == floor(stepChecker)
                            app.ex.scan(rowNum).nSteps = stepChecker;
                            app.ex.scan(rowNum).stepSize = str2double(newData);
                        else
                            stepChecker = round(stepChecker);
                            app.ex.scan(rowNum).nSteps = stepChecker;
                            app.ex.scan(rowNum).stepSize = (app.ex.scan(rowNum).bounds{1}(2) - app.ex.scan(rowNum).bounds{1}(1)) ./ (app.ex.scan(rowNum).nSteps-1);
                            changeWarning = sprintf('Step size changed to %.4g to allow for whole number of steps',app.ex.scan(rowNum).stepSize);
                            warndlg(changeWarning,'Altered Step Size')
                        end
                    else % If there are multiple addresses being scanned, automatically sets step size based on nsteps and bounds              
                        for ii = 1:addressNum
                            app.ex.scan(rowNum).stepSize(ii) = (app.ex.scan(rowNum).bounds{ii}(2) - app.ex.scan(rowNum).bounds{ii}(1)) ./ (app.ex.scan(rowNum).nSteps-1);
                        end
                        warndlg('Step size cannot be set when scanning multiple addresses, change bounds or number of steps instead','Cannot Change Step Size')
                    end
                case 6 %pulse addresses
                    addresses = split(newData,[", ",","," "]);
                    addresses = addresses(addresses ~= "");
                    app.ex.scan(rowNum).address = str2double(addresses);
                case 7
                    app.ex.scan(rowNum).notes = newData;

            end

            for ii = 1:numel(app.ex.scan)
                boundsNum = numel(app.ex.scan(ii).bounds);
                addressNum = numel(app.ex.scan(ii).address);
                stepsNum = numel(app.ex.scan(ii).stepSize);
                if addressNum > boundsNum
                    for jj = 1:(addressNum-boundsNum)
                        app.ex.scan(ii).bounds(boundsNum+jj) = app.ex.scan(ii).bounds(1);
                    end
                elseif addressNum < boundsNum
                    warndlg('Sets of bounds is greater than the number of addresses, deleting last set','Too Many Bounds')
                    app.ex.scan(ii).bounds = app.ex.scan(ii).bounds(1:addressNum);
                end  
                if addressNum > stepsNum
                    for jj = 1:(addressNum-stepsNum)
                        app.ex.scan(ii).stepSize(stepsNum+jj) = app.ex.scan(ii).stepSize(1);
                    end
                elseif addressNum<stepsNum
                    app.ex.scan(ii).stepSize = app.ex.scan(ii).stepSize(1:addressNum);
                end
            end

            ScanUpdate(app)
            OutputSettingsUpdate(app)

        end

        % Button pushed function: DeleteScanStep
        function DeleteScanStepPushed(app, event)
            if strcmpi(app.scanStatus,'running')
                errordlg('Cannot edit scan while running, abort scan to continue','Scan Running')
                return
            end
            address = app.ScanAddress.Value;
            currentLength = length([app.ex.scan.parameter]);

            if address > currentLength
                errordlg('Selected address does not exist','Invalid Address')
            elseif address == currentLength && address == 1
                errordlg('Cannot delete the last step','Cannot Delete')
            else
                fieldsToChange = {'parameter','bounds','nSteps','stepSize','completed','identifier','address','stepToView','notes'};
                fieldValues = {'delete'};
                app.ex.scan = ModifyAllFields(app,app.ex.scan,fieldsToChange,fieldValues,address);
            end

            ScanUpdate(app)
            % OutputSettingsUpdate(app) %Needed for step to view selection and the like
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            if strcmp(app.scanStatus,'running') || strcmp(app.scanStatus,'aborting')%Check aborting status as well**
                errordlg('Abort the what is currently running before attempting to reset','Abort First')
            else
                %Set ready status to true **
                app.scanStatus = 'reset';
                for ii = 1:numel(app.ex.scan) %Is this needed?**
                    app.ex.scan(ii).completed = 0;
                end
            end
            StatusUpdate(app)
        end

        % Cell edit callback: ScanStepTable
        function ScanStepTableCellEdit(app, event)
            newData = str2double(event.NewData);
            if isnan(newData)
                OutputSettingsUpdate(app)
                errordlg('Step to view must be a number','Invalid Step')
                return
            end
            if newData <= app.storedDataSettings.scan(event.Indices(1)).nSteps && newData > 0 && floor(newData) == newData
                app.storedDataSettings.scan(event.Indices(1)).stepToView = newData;
            else
                errordlg('Step to view must be an integer between 1 and total number of steps inclusive','Invalid Step')
            end
            OutputSettingsUpdate(app)
        end

        % Value changed function: RandomizescanCheckBox
        function RandomizescanCheckBoxValueChanged(app, event)
            app.ex.randomizeScanPoints = app.RandomizescanCheckBox.Value;
            ScanUpdate(app)
            OutputSettingsUpdate(app)
        end

        % Value changed function: ForcesquareaspectratioforimagesCheckBox
        function ForcesquareaspectratioforimagesCheckBoxValueChanged(app, event)
            app.displayInfo.squareRatio = app.ForcesquareaspectratioforimagesCheckBox.Value;
            OutputSettingsUpdate(app)
        end

        % Value changed function: BoxAddress
        function BoxAddressValueChanged(app, event)
            app.PulseBoxAddress.Value = app.BoxAddress.Value;
            SaveDataUpdate(app)
            PulseSequenceUpdate(app)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

            if strcmp(app.DataSetupsSwitch.Value,'Data') && (isempty(app.ex.data) || isempty(app.ex))
                errordlg('No data to save','Save Unsuccessful')
                return
            end

            %**rootSaveName now changed in 2 parts to allow proper
            %assessment of box address. Also 'Backlund lab shared' should
            %be a variable created in startup 
            rootSaveName = strcat(app.BoxAddress.Value,'\Backlund lab shared');

            %Checks if the directory is valid
            setupDirectory = struct2cell(dir(rootSaveName));
            if isempty(setupDirectory)
                errordlg('Current box address invalid','Invalid box address')
                return
            end

            rootSaveName = strcat(rootSaveName,'\data\',app.UserName.Value,'\Experiment Editor\',app.DataSetupsSwitch.Value);   

            if isempty(app.SaveFolderName.Value)
                errordlg('Need a folder name to save (save name box)','Cannot Save')
                return
            end
            
            %Checks if the given folders are present and makes them if they
            %aren't. Data folders are first sorted by date and then by name
            %while setup folders are the reverse
            if strcmp(app.DataSetupsSwitch.Value,'Data')
                rootSaveName = CheckFolder(app,rootSaveName,string(datetime('today','Format','yyyy-MM-dd')));
                rootSaveName = CheckFolder(app,rootSaveName,app.SaveFolderName.Value);
            else              
                
                rootSaveName = CheckFolder(app,rootSaveName,app.SaveFolderName.Value);
                
                versionDirectory = struct2cell(dir(rootSaveName));
                versionDirectory = versionDirectory(1,:);%Only name row
                if numel(versionDirectory) == 2
                    versionNumber = 1;
                else
                    versionDirectory = versionDirectory(:,3:end);
                    versionDirectory = cellfun(@(a)a(2:end-13),versionDirectory,'UniformOutput',false);
                    versionNumber = max(str2double(versionDirectory))+1;
                end
                newName = sprintf('v%d - %s',versionNumber,string(datetime('today','Format','yyyy-MM-dd')));
                rootSaveName = CheckFolder(app,rootSaveName,newName);
            end

            %At this point, the root name is the exact folder the files will
            %be saved in
            %Two functions are done here to make the code more legible

            if strcmp(app.DataSetupsSwitch.Value,'Data')
                DataSave(app,rootSaveName)
            else
                SetupSave(app,rootSaveName)
            end

            SaveDataUpdate(app)


        end

        % Cell edit callback: PlotLimits
        function PlotLimitsCellEdit(app, event)
            rowNum = event.Indices(1);
            switch event.Indices(2)
                case 2
                    if app.displayInfo.incorporateOffset(rowNum) == "true"
                        newData = str2double(event.NewData)- app.displayInfo.offset(rowNum);
                    else
                        newData = str2double(event.NewData);
                    end
                    if newData > app.displayInfo.limitsIfOverriden{rowNum}(2)
                        errordlg('Invalid minimum. Must be less than maximum.','Invalid Minimum')
                        return
                    end
                    app.displayInfo.limitsIfOverriden{rowNum}(1) = newData;

                case 3
                    if app.displayInfo.incorporateOffset(rowNum) == "true"
                        newData = str2double(event.NewData)- app.displayInfo.offset(rowNum);
                    else
                        newData = str2double(event.NewData);
                    end
                    if newData < app.displayInfo.limitsIfOverriden{rowNum}(1)
                        errordlg('Invalid maximum. Must be greater than minimum.','Invalid maximum')
                        return
                    end
                    app.displayInfo.limitsIfOverriden{rowNum}(2) = newData;

                case 4
                    if any(strcmpi(event.NewData,{'true','on','yes','0','T'}))
                        app.displayInfo.overrideLimits{rowNum} = "true";
                    else
                        app.displayInfo.overrideLimits{rowNum}  = "false";
                    end

                case 5
                    newData = str2double(event.NewData);
                    app.displayInfo.offset(rowNum) = newData;
                case 6
                    if any(strcmpi(event.NewData,{'true','on','yes','0','T'}))
                        app.displayInfo.incorporateOffset(rowNum) = "true";
                    else
                        app.displayInfo.incorporateOffset(rowNum)  = "false";
                    end

            end

            OutputSettingsUpdate(app)
        end

        % Value changed function: DisplayIterations
        function DisplayIterationsValueChanged(app, event)
            app.displayInfo.dataSelection = app.DisplayIterations.Value;
            OutputSettingsUpdate(app)
        end

        % Cell edit callback: StaticColorLimits
        function StaticColorLimitsCellEdit(app, event)
            newData = str2double(event.NewData);%Can fail
            if isnan(newData)
                errordlg('Invalid input. Must be a number','Invalid Input')
                OutputSettingsUpdate(app)
                return
            end
            if event.Indices(1) == 1 && newData >= app.displayInfo.staticColorLimits(2)
                errordlg('Invalid input. Minimum must be less than maximum','Invalid Input')
                OutputSettingsUpdate(app)
                return
            elseif event.Indices(1) == 2 && newData <= app.displayInfo.staticColorLimits(1)
                errordlg('Invalid input. Maximum must be more than minimum','Invalid Input')
                OutputSettingsUpdate(app)
                return
            end
            app.displayInfo.staticColorLimits(event.Indices(1)) = newData;

            OutputSettingsUpdate(app)
        end

        % Value changed function: UseStaticColorLimits
        function UseStaticColorLimitsValueChanged(app, event)
            app.displayInfo.useStaticColorLimits = app.UseStaticColorLimits.Value;
            app.displayInfo.overrideLimits{3,2} = app.displayInfo.useStaticColorLimits;
            app.displayInfo.alteredLimits = true;
            OutputSettingsUpdate(app)
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
            %REDO THIS FUNCTION FOR NEW OBJECTS*****

            %When loading data, overwrite necessary output tab settings (i.e. scan type)

            if any(strcmpi(app.LoadDates.Value,{'setups dates','data dates'})) || any(strcmpi(app.LoadNames.Value,{'data names','setups names'}))
                errordlg('please select a file version','No Version Selected')
                return
            end

            rootLoadName = strcat(app.BoxAddress.Value,'\Backlund lab shared\data\',app.UserName.Value);
            if strcmp(app.DataSetupsSwitch.Value,'Data')
                rootLoadName = strcat(rootLoadName,'\Experiment Editor\Data');
                %Makes the root now include the date then name
                rootLoadName = strcat(rootLoadName,'\', app.LoadDates.Value,'\',app.LoadNames.Value);

                loadName = strcat(rootLoadName,'\', 'MatLab_Data.mat');
                load(loadName,"data")
                loadName = strcat(rootLoadName,'\', 'MatLab_Data_Settings.mat');
                load(loadName,"dataSettings")

                app.ex.data = data;
                app.storedDataSettings  = dataSettings;

                SaveDataUpdate(app)
                DisplayUpdate(app)

                uialert(app.UIFigure,'Data Loaded!','Save Successful','Icon','success')

            else %Setups
                rootLoadName = strcat(rootLoadName,'\Experiment Editor\Setups');
                %Makes the root now include the name then date
                rootLoadName = strcat(rootLoadName,'\', app.LoadNames.Value,'\',app.LoadDates.Value);

                loadName = strcat(rootLoadName,'\', 'MatLab_Setup_Settings');
                load(loadName,"setupSettings")

                %Loads the saved properties that don't require connection
                %to an instruments (scans, optimizationInfo etc.)
                generalNames = fieldnames(setupSettings.general);
                for ii = 1:numel(generalNames)
                    app.ex.(generalNames{ii}) = setupSettings.general.(generalNames{ii});
                end
                
                %Loads the saved properties associated with each instrument
                %that is connected. If the instrument isn't connected,
                %store the saved information in storedSetupSettings.              
                if ~isempty(setupSettings.instruments)
                    instrumentNames = fieldnames(setupSettings.instruments);
                    for ii = 1:numel(instrumentNames)
                        if ~isempty(app.ex.(instrumentNames{ii})) && app.ex.(instrumentNames{ii}).connected == 1
                            propNames = fieldnames(setupSettings.instruments.(instrumentNames{ii}));
                            for jj = 1:numel(propNames)
                                %special exception made for userSequence
                                %because it's not an editable property
                                if c2s(propNames{jj}) == "userSequence"
                                    LoadPulseSequence(app,setupSettings.instruments.pulseBlaster.userSequence)
                                else
                                    app.ex.(instrumentNames{ii}).(propNames{jj}) = setupSettings.instruments.(instrumentNames{ii}).(propNames{jj});
                                end
                            end
                        else
                            app.storedSetupSettings.(instrumentNames{ii}) = setupSettings.instruments.(instrumentNames{ii});
                        end
                    end
                end
                        

                AppUpdate(app)

                uialert(app.UIFigure,'Setup Loaded!','Save Successful','Icon','success')

            end


        end

        % Button pushed function: ScreenshotButton
        function ScreenshotButtonPushed(app, event)
            %Saves the screenshot to current date folder on box for the
            %individual

            %This is the name for the data folder for this app for this person
            rootSaveName = strcat(app.BoxAddress.Value,'\Backlund lab shared\data\',app.UserName.Value,'\Experiment Controller SM Data');

            %Load the directory
            fileList = struct2cell(dir(rootSaveName));

            %If no folders appear, the box address is almost certainly the
            %issue
            if isempty(fileList)
                errordlg('Invalid box address. Change on the Save/Load tab','Invalid Box Address')
                return
            end

            %Finds current date
            currentDate = string(datetime('today','Format','yyyy-MM-dd'));

            %Check to see if the folder in question exists, then, if not, make
            %one. Update root save name to include new folder
            fileExists = any(strcmp(currentDate,fileList),"all");
            if ~fileExists
                mkdir(rootSaveName,currentDate)
            end
            rootSaveName = strcat(rootSaveName,'\',currentDate);

            %Check to see if the folder in question exists, then, if not, make
            %one. Update root save name to include new folder
            fileList = struct2cell(dir(rootSaveName));
            fileExists = any(strcmp('Screenshots',fileList),"all");
            if ~fileExists
                mkdir(rootSaveName,'Screenshots')
            end
            rootSaveName = strcat(rootSaveName,'\','Screenshots');

            %Check to see if the file in question already exists
            fileList = struct2cell(dir(rootSaveName));
            fileList = fileList(1,:); %only name row
            fileExists = contains(fileList,app.SaveFolderName.Value);

            if ~any(fileExists,'all')
                %No file with this name exists, so it is saved just with the
                %base name
                fullSaveName = strcat(rootSaveName,'\',app.SaveFolderName.Value);
            elseif sum(fileExists,"all") == 1
                %Only 1 file with the name exists so the next one is saved with
                %a 2
                fullSaveName = strcat(rootSaveName,'\',app.SaveFolderName.Value,' 2');
            else
                versionNumber = cellfun(@(a)str2double(a(numel(app.SaveFolderName.Value)+2:end-4)),fileList,'UniformOutput',false);
                versionNumber = max(cell2mat(versionNumber(cellfun(@(a)~isnan(a),versionNumber))))+1;
                fullSaveName = sprintf('%s%s %d',strcat(rootSaveName,'\'),app.SaveFolderName.Value,versionNumber);
            end

            %Takes a screenshot of the app's current screen
            exportapp(app.UIFigure,strcat(fullSaveName,'MatLab_App_Screenshot.png'))

            %Creates temporary figure and axes that match the display axes so
            %that an image of them can be saved
            tempFigure = figure('Visible','off');
            tempAxes = copyobj(app.displayInfo.axes,tempFigure);
            %Changes appearance of figure to make a better picture
            set(tempAxes, 'Units', 'Normalized'); % First change to normalized units.
            set(tempAxes, 'OuterPosition', [0.05, 0.05, .9, .9]); % [xLeft, yBottom, width, height]
            %Saves the figure then closes it
            saveas(tempAxes,strcat(fullSaveName,'MatLab_Axes_Screenshot','png'))
            close(tempFigure)

        end

        % Button pushed function: CollectdatacontinuouslyButton
        function CollectdatacontinuouslyButtonPushed(app, event)
         
        end

        % Button pushed function: CopyappobjecttoworkspaceButton
        function CopyappobjecttoworkspaceButtonPushed(app, event)
            %Makes a copy of the current app in the workspace. Mainly for
            %debugging
            assignin("base","app",app)
        end

        % Button pushed function: StageLoad
        function StageLoadButtonPushed(app, event)
            app.ex = loadInstruments(app.ex,'PIstage',app.StageConfig.Value,false);
            LoadSavedSettings(app,'PIstage')
            AppUpdate(app)
        end

        % Button pushed function: PulseBlasterLoad
        function PulseBlasterLoadButtonPushed(app, event)
            app.ex = loadInstruments(app.ex,'pulseBlaster',app.PulseBlasterConfig.Value,false);
            %**Now uses total loop when loaded
            app.ex.pulseBlaster.useTotalLoop = true;
            LoadSavedSettings(app,'pulseBlaster')
            AppUpdate(app)
        end

        % Button pushed function: PulseBlasterUnload
        function PulseBlasterUnloadButtonPushed(app, event)
            disconnect(app.ex.pulseBlaster)
            AppUpdate(app)
        end

        % Button pushed function: SproutLaserLoad
        function SproutLaserLoadButtonPushed(app, event)
            % app = loadInstrument(app,'ndYAG','laser');
            %AppUpdate(app)
        end

        % Button pushed function: SproutLaserUnload
        function SproutLaserUnloadButtonPushed(app, event)
            % CleanUp('laser')
            % AppUpdate(app)
        end

        % Button pushed function: SRSRFLoad
        function SRSRFLoadButtonPushed(app, event)
            app.ex = loadInstruments(app.ex,'SRS_RF',app.SRSRFConfig.Value,false);
            LoadSavedSettings(app,'SRS_RF')
            AppUpdate(app)
        end

        % Button pushed function: SRSRFUnload
        function SRSRFUnloadButtonPushed(app, event)
            disconnect(app.ex.SRS_RF)
            AppUpdate(app)
        end

        % Button pushed function: WindfreakRFLoad
        function WindfreakRFLoadButtonPushed(app, event)
             app.ex = loadInstruments(app.ex,'windfreak_RF',app.WindfreakRFConfig.Value);
             LoadSavedSettings(app,'windfreak_RF')
             AppUpdate(app)
        end

        % Button pushed function: WindfreakRFUnload
        function WindfreakRFUnloadButtonPushed(app, event)
            disconnect(app.ex.windfreak_RF)
            AppUpdate(app)
        end

        % Button pushed function: DAQLoad
        function DAQLoadButtonPushed(app, event)
            app.ex = loadInstruments(app.ex,'DAQ',app.DAQConfig.Value);
            LoadSavedSettings(app,'DAQ')
            AppUpdate(app)
        end

        % Button pushed function: DAQUnload
        function DAQUnloadButtonPushed(app, event)
            disconnect(app.ex.DAQ) 
            AppUpdate(app)
        end

        % Value changed function: OffWhenDeleted
        function OffWhenDeletedValueChanged(app, event)
            %offWhenDeleted is whether the laser should turn off when its object is deleted
            % if ~checkInstrumentStatus(app,'ndYAG'), return; end
            % 
            % app.ex.ndYAG.offWhenDeleted = app.OffWhenDeletedValueChanged.Value;
            GeneralUpdate(app)
        end

        % Value changed function: WaitForLaserEquilibrium
        function WaitForLaserEquilibriumValueChanged(app, event)
            %waitForEquilibrium is a looping function that waits until the actual power read off the laser equals the set
            % %power
            % if ~checkInstrumentStatus(app,'ndYAG'), return; end

            % app.ex.ndYAG.waitForEquilibrium = app.WaitForLaserEquilibriumValueChanged.Value;
            GeneralUpdate(app)
        end

        % Value changed function: SetLaserPower
        function SetLaserPowerValueChanged(app, event)
            % setPower changes the current power setting of the laser
            % if ~checkInstrumentStatus(app,'ndYAG'), return; end
            % 
            % app.ex.ndYAG.setPower = app.SetLaserPowerValueChanged.Value;
            GeneralUpdate(app)
        end

        % Value changed function: ToggleLaser
        function ToggleLaserValueChanged(app, event)
            %Turns laser on or off
            % if ~checkInstrumentStatus(app,'ndYAG'), return; end
            % 
            % if strcmp(app.SetLaserPowerValueChanged.Value,'on')
            %     app.ex.ndYAG.enabled = true;
            % else
            %     app.ex.ndYAG.enabled = false;
            % end
            GeneralUpdate(app)
        end

        % Button pushed function: RefreshLaserPowerReading
        function RefreshLaserPowerReadingButtonPushed(app, event)
            %Obtains the current output reading of the laser
            % if ~checkInstrumentStatus(app,'ndYAG'), return; end
            % 
            % app.ex.ndYAG = queryActualPower(app.ex.ndYAG);
            GeneralUpdate(app)
        end

        % Value changed function: DifferentiatesignalandreferenceCheckBox
        function DifferentiatesignalandreferenceCheckBoxValueChanged(app, event)
            %Toggles differentiation between signal and reference. If s/r differentiation is off, taking data will be more
            %efficient but unable to produce contrast as only a single value will be generated
            if ~checkInstrumentStatus(app,'DAQ'), return; end

            app.ex.DAQ.differentiateSignal = app.DifferentiatesignalandreferenceCheckBoxValueChanged.Value;
            GeneralUpdate(app)
        end

        % Value changed function: XPosition
        function XPositionValueChanged(app, event)
            %If stage is loaded, moves stage to desired absolute position
            if ~checkInstrumentStatus(app,'PIstage'), return; end

            app.ex.PIstage = absoluteMove(app.ex.PIstage,'x',app.XPosition.Value);
            PositionUpdate(app)
        end

        % Value changed function: YPosition
        function YPositionValueChanged(app, event)
            %If stage is loaded, moves stage to desired absolute position
            if ~checkInstrumentStatus(app,'PIstage'), return; end

            app.ex.PIstage = absoluteMove(app.ex.PIstage,'y',app.YPosition.Value);
            PositionUpdate(app)
        end

        % Value changed function: ZPosition
        function ZPositionValueChanged(app, event)
            %If stage is loaded, moves stage to desired absolute position
            if ~checkInstrumentStatus(app,'PIstage'), return; end

            app.ex.PIstage = absoluteMove(app.ex.PIstage,'z',app.ZPosition.Value);
            PositionUpdate(app)
        end

        % Button pushed function: XDown
        function XDownButtonPushed(app, event)
            %If stage is loaded, moves stage by negative increment value
            if ~checkInstrumentStatus(app,'PIstage'), return; end

            app.ex.PIstage = relativeMove(app.ex.PIstage,'x',-app.XIncrement.Value);
            PositionUpdate(app)
        end

        % Button pushed function: YDown
        function YDownButtonPushed(app, event)
            %If stage is loaded, moves stage by negative increment value
            if ~checkInstrumentStatus(app,'PIstage'), return; end

            app.ex.PIstage = relativeMove(app.ex.PIstage,'y',-app.YIncrement.Value);
            PositionUpdate(app)
        end

        % Button pushed function: ZDown
        function ZDownButtonPushed(app, event)
            %If stage is loaded, moves stage by negative increment value
            if ~checkInstrumentStatus(app,'PIstage'), return; end

            app.ex.PIstage = relativeMove(app.ex.PIstage,'z',-app.ZIncrement.Value);
            PositionUpdate(app)
        end

        % Button pushed function: XUp
        function XUpButtonPushed(app, event)
            %If stage is loaded, moves stage by positive increment value
            if ~checkInstrumentStatus(app,'PIstage'), return; end

            app.ex.PIstage = relativeMove(app.ex.PIstage,'x',app.XIncrement.Value);
            PositionUpdate(app)
        end

        % Button pushed function: YUp
        function YUpButtonPushed(app, event)
            %If stage is loaded, moves stage by positive increment value
            if ~checkInstrumentStatus(app,'PIstage'), return; end

            app.ex.PIstage = relativeMove(app.ex.PIstage,'y',app.YIncrement.Value);
            PositionUpdate(app)
        end

        % Button pushed function: ZUp
        function ZUpButtonPushed(app, event)
            %If stage is loaded, moves stage by positive increment value
            if ~checkInstrumentStatus(app,'PIstage'), return; end

            app.ex.PIstage = relativeMove(app.ex.PIstage,'z',app.ZIncrement.Value);
            PositionUpdate(app)
        end

        % Button pushed function: XFineReset
        function XFineResetPushed(app, event)
            %If stage is loaded, resets fine stage to center and moves coarse stage to compensate
            if ~checkInstrumentStatus(app,'PIstage'), return; end

            app.ex.PIstage = fineReset(app.ex.PIstage,'x','middle');
            PositionUpdate(app)
        end

        % Button pushed function: YFineReset
        function YFineResetButtonPushed(app, event)
            %If stage is loaded, resets fine stage to center and moves coarse stage to compensate
            if ~checkInstrumentStatus(app,'PIstage'), return; end

            app.ex.PIstage = fineReset(app.ex.PIstage,'y','middle');
            PositionUpdate(app)
        end

        % Button pushed function: ZFineReset
        function ZFineResetButtonPushed(app, event)
            %If stage is loaded, resets fine stage to center and moves coarse stage to compensate
            if ~checkInstrumentStatus(app,'PIstage'), return; end

            app.ex.PIstage = fineReset(app.ex.PIstage,'z','middle');
            PositionUpdate(app)
        end

        % Value changed function: StageTolerance
        function StageToleranceValueChanged(app, event)
            %If stage is loaded, changes maximum tolerance for stage movements i.e. how far can the stage's measured
            %distance be from the target location
            if ~checkInstrumentStatus(app,'PIstage'), return; end

            app.ex.PIstage.tolerance = app.StageTolerance.Value;
            PositionUpdate(app)
        end

        % Value changed function: StageSettlingTime
        function StageSettlingTimeValueChanged(app, event)
            %If stage is loaded, changes time stage pauses for every movement
            if ~checkInstrumentStatus(app,'PIstage'), return; end

            app.ex.PIstage.pauseTime = app.StageSettlingTime.Value;
            InfrequentUpdate(app)
        end

        % Button pushed function: AddOptimizationStep
        function AddOptimizationStepPushed(app, event)
            %Adds additional step whose settings can then be altered in the optimization table
            % If the Addrres is over the maximum, gives a warning and
            % chnages the address to the maximum
            if strcmp(app.OptimizationInsertorReplace.Value, 'Replace')
                if app.StageAddress.Value > numel(app.ex.optimizationInfo.stageAxes)
                warnMessage = sprintf('Index greater than possible length, changed to %d',numel(app.ex.optimizationInfo.stageAxes));
                warndlg(warnMessage,'Over Maximum Index')
                app.StageAddress.Value = numel(app.ex.optimizationInfo.stageAxes);
                end
            else
                if app.StageAddress.Value > numel(app.ex.optimizationInfo.stageAxes) + 1
                warnMessage = sprintf('Index greater than possible length, changed to %d',numel(app.ex.optimizationInfo.stageAxes)+1);
                warndlg(warnMessage,'Over Maximum Index')
                app.StageAddress.Value = numel(app.ex.optimizationInfo.stageAxes) + 1;
                end
            end
            % Inserts new row and shifts rows with a greater address up 1
            if strcmp(app.OptimizationInsertorReplace.Value,'Insert') && ~isempty(app.ex.optimizationInfo.stageAxes) && app.StageAddress.Value <= numel(app.ex.optimizationInfo.stageAxes)
                app.ex.optimizationInfo.stageAxes(app.StageAddress.Value+1:end+1) =  app.ex.optimizationInfo.stageAxes(app.StageAddress.Value:end);
                app.ex.optimizationInfo.radius(app.StageAddress.Value+1:end+1) =  app.ex.optimizationInfo.radius(app.StageAddress.Value:end);
                app.ex.optimizationInfo.steps{app.StageAddress.Value+1:end+1} =  app.ex.optimizationInfo.steps{app.StageAddress.Value:end};
            end
            % creates optimization info with default settings at the
            % specified address
            if ~isempty(app.ex.optimizationInfo.stageAxes)
                app.ex.optimizationInfo.stageAxes(app.StageAddress.Value) = "x";
            else
                app.ex.optimizationInfo.stageAxes = "x";
            end
            app.ex.optimizationInfo.radius(app.StageAddress.Value) = 1;
            app.ex.optimizationInfo.steps{app.StageAddress.Value} = 5;
            app.StageOptimizationInstructions.ColumnEditable = [true true true];

            PositionUpdate(app)
        end

        % Cell edit callback: StageOptimizationInstructions
        function StageOptimizationInstructionsCellEdit(app, event)
            switch event.Indices(2) %column (stage/radius/steps)

                case 1 %stage
                    if any(cellfun(@(a)strcmpi(a,event.NewData),{"x","y","z"}))
                        app.ex.optimizationInfo.stageAxes(event.Indices(1)) = convertCharsToStrings(event.NewData);
                    else
                        errordlg('Fuser stage must be x, y, or z','Improper Axis')
                    end
                case 2 %radius
                    app.ex.optimizationInfo.radius(event.Indices(1)) = str2double(event.NewData);
                case 3 %steps
                    if rem(str2double(event.NewData),2) == 1
                        app.ex.optimizationInfo.steps{event.Indices(1)} = str2double(event.NewData);
                    else
                        errordlg('Optimization number of steps must be odd','Must Be Odd')
                    end

            end
            PositionUpdate(app)
        end

        % Button pushed function: AddScanStep
        function AddScanStepPushed(app, event)
            if strcmpi(app.scanStatus,'running')
                errordlg('Cannot edit scan while running, abort scan to continue','Scan Running')
                return
            end
            address = app.ScanAddress.Value;
            currentLength = length([app.ex.scan.parameter]);

            if address > currentLength + 1
                address = currentLength + 1;
                warnMessage = sprintf('Address greater than possible length, changed to %d',address);
                warndlg(warnMessage,'Over Maximum Address')
            end

            fieldsToChange = {'parameter','bounds','nSteps','stepSize','completed','identifier','address','stepToView','notes'};
            fieldValues = {"x",{[1 10]},10,1,0,"stage",0,1,""};
            app.ex.scan = ModifyAllFields(app,app.ex.scan,fieldsToChange,fieldValues,address);

            ScanUpdate(app)
            % OutputSettingsUpdate(app) %Needed for step to view selection and the like
        end

        % Value changed function: windfreakRFFrequency
        function windfreakRFFrequencyValueChanged(app, event)
            %Changes frequency of windfreak RF output
            if ~checkInstrumentStatus(app,'windfreak_RF'), return; end

            app.ex.windfreak_RF.frequency = app.RFFrequency.Value;
            GeneralUpdate(app)
        end

        % Value changed function: windfreakRFAmplitude
        function windfreakRFAmplitudeValueChanged(app, event)
            %Changes amplitude of windfreak RF output
            if ~checkInstrumentStatus(app,'windfreak_RF'), return; end

            app.ex.windfreak_RF.amplitude = app.windfreakRFAmplitude.Value;
            GeneralUpdate(app)
        end

        % Value changed function: windfreakRFModulationDropDown
        function WindfreakRFwindfreakRFModulationDropDownValueChanged(app, event)
            if ~checkInstrumentStatus(app,'windfreak_RF'), return; end
            if strcmpi("off",app.SRS_RFModulationDropDown.Value)
               app.ex.windfreak_RF.modulationEnabled = "Off";
            else
               app.ex.windfreak_RF.modulationEnabled = "On";
               app.ex.windfreak_RF.modulationType = app.SRS_RFModulationDropDown.Value;
            end
            GeneralUpdate(app)
        end

        % Value changed function: SRS_RFFrequency
        function SRS_RFFrequencyValueChanged(app, event)
            if ~checkInstrumentStatus(app,'SRS_RF'), return; end

            app.ex.SRS_RF.frequency = app.SRS_RFFrequency.Value;
            GeneralUpdate(app)
        end

        % Value changed function: SRS_RFAmplitude
        function SRS_RFAmplitudeValueChanged(app, event)
            if ~checkInstrumentStatus(app,'SRS_RF'), return; end

            app.ex.SRS_RF.amplitude = app.SRS_RFAmplitude.Value;
            GeneralUpdate(app)
        end

        % Value changed function: SRS_RFModulationDropDown
        function SRS_RFModulationDropDownValueChanged(app, event)
            if ~checkInstrumentStatus(app,'SRS_RF'), return; end
            if strcmpi("off",app.SRS_RFModulationDropDown.Value)
               app.ex.SRS_RF.modulationEnabled = "Off";
            else
               app.ex.SRS_RF.modulationEnabled = "On";
               app.ex.SRS_RF.modulationType = app.SRS_RFModulationDropDown.Value;
            end
            GeneralUpdate(app)
        end

        % Value changed function: StageAutoOptimization
        function StageAutoOptimizationValueChanged(app, event)
         app.ex.optimizationInfo.enableOptimization = app.StageAutoOptimization.Value;
        end

        % Value changed function: OptimizationmethodDropDown
        function OptimizationmethodDropDownValueChanged(app, event)
            if strcmpi("max value", app.OptimizationmethodDropDown.Value)
                app.ex.optimizationInfo.algorithmType = 'max value';
            else
                app.ex.optimizationInfo.algorithmType = 'gaussian';
            end
        end

        % Value changed function: OptimizationInterval
        function OptimizationIntervalValueChanged(app, event)
            app.ex.optimizationInfo.timeBetweenOptimizations = app.OptimizationInterval.Value;
        end

        % Value changed function: OptimizationparameterDropDown
        function OptimizationparameterDropDownValueChanged(app, event)
            if strcmpi("Counts (RF on)", app.OptimizationparameterDropDown.Value)
                app.ex.optimizationInfo.rfStatus = 'on';
            elseif strcmpi("Counts (RF off)",app.OptimizationparameterDropDown.Value)
                app.ex.optimizationInfo.rfStatus = 'off';
            else %contrast
                app.ex.optimizationInfo.rfStatus = 'contrast';
            end
        end

        % Value changed function: OptimizationPercentageCheck
        function OptimizationPercentageCheckValueChanged(app, event)
         app.ex.optimizationInfo.usePercentageDifference = app.OptimizationPercentageCheck.Value;         
        end

        % Value changed function: OptimizationTimer
        function OptimizationTimerValueChanged(app, event)
         app.ex.optimizationInfo.useTimer = app.OptimizationTimer.Value;         
        end

        % Value changed function: OptimizationPercentage
        function OptimizationPercentageValueChanged(app, event)
         app.ex.optimizationInfo.percentageToForceOptimization = app.OptimizationPercentage.Value;         
        end

        % Button pushed function: DeleteOptimizationStep
        function DeleteOptimizationStepPushed(app, event)
            address = app.StageAddress.Value;
            currentLength = length(app.ex.optimizationInfo.stageAxes);

            if address > currentLength
                errordlg('Selected address does not exist','Invalid Address')
            elseif address == currentLength && address == 1
                errordlg('Cannot delete the last step','Cannot Delete')
            else
                app.ex.optimizationInfo.stageAxes(address) = [];
                app.ex.optimizationInfo.radius(address) = [];
                app.ex.optimizationInfo.steps(address) = [];
                % fieldsToChange = {'stageAxes','radius','steps'};
                % fieldValues = {'delete'};
                % app.ex.optimizationInfo = AddToAllFields(app,app.ex.optimizationInfo,fieldsToChange,fieldValues,address);
            end
            PositionUpdate(app)
        end

        % Button pushed function: BeginmanualoptimizationButton
        function BeginmanualoptimizationButtonPushed(app, event)
            if ~checkInstrumentStatus(app,'PIstage'), return; end
            if app.Numberofmanualoptimizations.Value < 1
                return
            end
            for ii = 1:app.Numberofmanualoptimizations.Value
                app.ex = stageOptimization(app.ex);
            end
            PositionUpdate(app)
            uialert(app.UIFigure,'Manual Optimization Complete!','Success','Icon','success')
        end

        % Value changed function: AddStepButton
        function AddStepButtonValueChanged(app, event)
            if isempty(app.ex.pulseBlaster)
                message = sprintf('Pulse blaster must be connected to edit pulse sequence');
                warndlg(message,'Pulse Blaster Not Connected')
                return
            end

            address = app.PulseAddress.Value;

            currentLength = length([app.ex.pulseBlaster.userSequence]);

            if address > currentLength + 1
                address = currentLength + 1;
                warnMessage = sprintf('Address greater than possible length, changed to %d',address);
                warndlg(warnMessage,'Over Maximum Address')
            end

            defaultPulse.activeChannels = {};
            defaultPulse.duration = 10;
            defaultPulse.notes = 'new pulse';

            app.ex.pulseBlaster = addPulse(app.ex.pulseBlaster,defaultPulse,address);

            PulseSequenceUpdate(app)

        end

        % Value changed function: DeleteStepButton
        function DeleteStepButtonValueChanged(app, event)
            if isempty(app.ex.pulseBlaster)
                message = sprintf('Pulse blaster must be connected to edit pulse sequence');
                warndlg(message,'Pulse Blaster Not Connected')
                return
            end

            address = app.PulseAddress.Value;
            currentLength = length([app.ex.pulseBlaster.userSequence]);

            if address > currentLength
                errordlg('Selected address does not exist','Invalid Address')
            elseif address == currentLength && address == 1
                errordlg('Cannot delete the last step','Cannot Delete')
            else
               app.ex.pulseBlaster = deletePulse(app.ex.pulseBlaster,address); 
            end
            PulseSequenceUpdate(app)
            
        end

        % Cell edit callback: PulseSequenceTable
        function PulseSequenceTableCellEdit(app, event)
            rowNum = event.Indices(1);
            switch event.Indices(2)
                case 2 %Active Channels                    
                    newChannels = lower(c2s(app.PulseSequenceTable.Data(rowNum,2)));
                    channels = {};
                    if contains(newChannels,["aom","laser"])
                        channels = {channels{1:end},'AOM'};
                    end
                    if contains(newChannels,["data","daq"])
                        channels = {channels{1:end},'DAQ'};
                    end
                    if contains(newChannels,"sig")
                        channels = {channels{1:end},'Signal'};
                    end
                    if contains(newChannels,"rf")
                        channels = {channels{1:end},'RF'};
                    end
                    if contains(newChannels, [" i", "i "]) || startsWith(newChannels,'i') || endsWith(newChannels,'i')
                        channels = {channels{1:end},'I'};
                    end
                    if contains(newChannels,[" q","q "]) || startsWith(newChannels,'q') || endsWith(newChannels,'q')
                        channels = {channels{1:end},'Q'};
                    end
                    modifyPulse(app.ex.pulseBlaster,rowNum,'activeChannels',channels)
                case 3 %Binnary channels
                    newData = s2c(app.PulseSequenceTable.Data(rowNum,3));
                    modifyPulse(app.ex.pulseBlaster,rowNum,'binary',newData)
                case 4 %Output Number
                    newOutput = double(app.PulseSequenceTable.Data(rowNum,4));
                    modifyPulse(app.ex.pulseBlaster,rowNum,'output',newOutput)
                case 5 %Duratione
                    newDuration = double(app.PulseSequenceTable.Data(rowNum,5));
                    modifyPulse(app.ex.pulseBlaster,rowNum,'Duration',newDuration)
                case 6 %Direction Type
                    newDirection = s2c(app.PulseSequenceTable.Data(rowNum,6));
                    modifyPulse(app.ex.pulseBlaster,rowNum,'direction',newDirection)
                case 7 %Context Info
                    newContext = double(app.PulseSequenceTable.Data(rowNum,7));
                    modifyPulse(app.ex.pulseBlaster,rowNum,'context',newContext)
                case 8 %notes
                    newNotes = s2c(app.PulseSequenceTable.Data(rowNum,8));
                    modifyPulse(app.ex.pulseBlaster,rowNum,'notes',newNotes)
            end
            PulseSequenceUpdate(app)
            
        end

        % Value changed function: CopytoButton
        function CopytoButtonValueChanged(app, event)
            %converts character array to string which is split into string
            %array based on location of commas and spaces. For loop allows
            %for the interprtation of something like 1:4 as addresses
            %1,2,3,4.
            addressToCopy = app.PulseAddress.Value;
            copiedAddresses = split(c2s(app.CopyAddresses.Value),[", ",","," "]);
            copiedAddresses = copiedAddresses(copiedAddresses ~= "");
            addressNums = [];
            for ii = 1:length(copiedAddresses)
                if contains(copiedAddresses(ii),":") || contains(copiedAddresses(ii),"-")
                    bounds = str2double(split(copiedAddresses(ii),[":","-"]));
                    addressNums = [addressNums, min(bounds):max(bounds)];
                else
                    addressNums = [addressNums, str2double(copiedAddresses(ii))];
                end
            end
            
            
            nonnumbers = false;
            errAddresses = [];

            for ii = 1:length(addressNums)
                currentLength = length([app.ex.pulseBlaster.userSequence]);
                if isnan(addressNums(ii))
                    nonnumbers = true;
                elseif addressNums(ii) > currentLength +1
                    errAddresses = [errAddresses,addressNums(ii)];
                else
                pulseInfo.activeChannels = app.ex.pulseBlaster.userSequence(addressToCopy).activeChannels;
                pulseInfo.duration = app.ex.pulseBlaster.userSequence(addressToCopy).duration;
                pulseInfo.notes = app.ex.pulseBlaster.userSequence(addressToCopy).notes;
            
                app.ex.pulseBlaster = addPulse(app.ex.pulseBlaster,pulseInfo,addressNums(ii));
                    
                end
            end

            if nonnumbers
                warnMessage = sprintf('Address Values must be numeric');
                warndlg(warnMessage, 'Nonnumeric Values')
            end
            if ~isempty(errAddresses)
                errAddresses = num2str(errAddresses);
                message = sprintf('Addresses %s greater than possible length', errAddresses);
                warndlg(message,'Over Maximum Address')
            end

           PulseSequenceUpdate(app)
        end

        % Value changed function: ThingToPlot
        function ThingToPlotValueChanged(app, event)
   

            chooseVisiblePlot(app)
            DisplayUpdate(app)
            UpdatePlotlabel(app)
        end

        % Value changed function: PulseBoxAddress
        function PulseBoxAddressValueChanged(app, event)
            app.BoxAddress.Value = app.PulseBoxAddress.Value;
            SaveDataUpdate(app)
            PulseSequenceUpdate(app)
        end

        % Value changed function: DataSetupsSwitch
        function DataSetupsSwitchValueChanged(app, event)
            SaveDataUpdate(app)    
        end

        % Value changed function: UserName
        function UserNameValueChanged(app, event)
            SaveDataUpdate(app)
        end

        % Value changed function: LoadNames
        function LoadNamesValueChanged(app, event)
            SaveDataUpdate(app)            
        end

        % Value changed function: LoadDates
        function LoadDatesValueChanged(app, event)
            SaveDataUpdate(app)
        end

        % Value changed function: UserName2
        function UserName2ValueChanged(app, event)
            PulseSequenceUpdate(app)          
        end

        % Value changed function: PulseSequenceNames
        function PulseSequenceNamesValueChanged(app, event)
            PulseSequenceUpdate(app)

            if c2s(app.PulseSequenceNames.Value) ~= "Pulse Sequence Names"
                rootName = strcat(app.BoxAddress.Value,'\Backlund lab shared\data\',...
                app.UserName2.Value,'\Experiment Editor\Pulse Sequences\',app.PulseSequenceNames.Value);
                load(rootName,"savedPulse")
                app.PulseUserNotes.Value = savedPulse.Notes;
            end
        end

        % Value changed function: SavePulseSequence
        function SavePulseSequenceValueChanged(app, event)
            if isempty(app.ex.pulseBlaster)
                message = sprintf('Pulse blaster must be connected to save pulse sequence');
                warndlg(message,'Pulse Blaster Not Connected')
                return
            end

            if isempty(app.ex.pulseBlaster.userSequence) 
                errordlg('No sequence to save','Save Unsuccessful')
                return
            end

            rootSaveName = strcat(app.BoxAddress.Value,'\Backlund lab shared\data\',app.UserName2.Value,'\Experiment Editor\Pulse Sequences');

            %Checks if the directory is valid. Even if it is empty, there will
            %be 2 "fake" files
            setupDirectory = struct2cell(dir(rootSaveName));
            if isempty(setupDirectory)
                errordlg('Current box address invalid','Invalid box address')
                return
            end

            if isempty(app.PulseSaveName.Value)
                errordlg('Need a folder name to save (save name box)','Cannot Save')
                return
            end

            savedPulse = [];
            savedPulse.Sequence = app.ex.pulseBlaster.userSequence;
            savedPulse.Notes = app.PulseUserNotes.Value;
            mSaveName = strcat(rootSaveName,'\', app.PulseSaveName.Value);
            save(mSaveName,"savedPulse")
            uialert(app.UIFigure,'Pulse Sequence Saved!','Save Successful','Icon','success')

            PulseSequenceUpdate(app)
        end

        % Value changed function: LoadPulse
        function LoadPulseValueChanged(app, event)
            if isempty(app.ex.pulseBlaster)
                message = sprintf('Pulse blaster must be connected to save pulse sequence');
                warndlg(message,'Pulse Blaster Not Connected')
                return
            end

            if c2s(app.PulseSequenceNames.Value) ~= "Pulse Sequence Names"
                rootName = strcat(app.BoxAddress.Value,'\Backlund lab shared\data\',...
                app.UserName2.Value,'\Experiment Editor\Pulse Sequences\',app.PulseSequenceNames.Value);
                load(rootName,"savedPulse")
                LoadPulseSequence(app,savedPulse.Sequence)
            end

            PulseSequenceUpdate(app)
            
        end

        % Value changed function: yAxisScanNum
        function yAxisScanNumValueChanged(app, event)
            if isempty(app.ex.data)
                return
            end
            if numel(app.storedDataSettings.scan) < 2 && app.yAxisScanNum.Value == 1 && app.xAxisScanNum.Value == 1
                app.xAxisScanNum.Value = 0;
            end
            if app.yAxisScanNum.Value > numel(app.storedDataSettings.scan)
                errordlg('Scan To display cannot be greater than the number of scan, setting to 0','Cannot Display Scan')
            end
            DisplayUpdate(app)
            UpdatePlotlabel(app)

        end

        % Value changed function: xAxisScanNum
        function xAxisScanNumValueChanged(app, event)
            if isempty(app.ex.data)
                return
            end
            if numel(app.storedDataSettings.scan) < 2 && app.yAxisScanNum.Value == 1 && app.xAxisScanNum.Value == 1
                app.yAxisScanNum.Value = 0;
            end
            if app.xAxisScanNum.Value > numel(app.storedDataSettings.scan)
                errordlg('Scan To display cannot be greater than the number of scan, setting to 0','Cannot Display Scan')
            end
            
            DisplayUpdate(app)
            UpdatePlotlabel(app)
        end

        % Button pushed function: ForceplotupdateButton
        function ForceplotupdateButtonPushed(app, event)
            DisplayUpdate(app)
        end

        % Value changed function: PulseNumToPlot
        function PulseNumToPlotValueChanged(app, event)
            DisplayUpdate(app)
        end

        % Value changed function: TotalLoops
        function TotalLoopsValueChanged(app, event)
      
            %**Added function for changing number of loops
            %Doesn't take duration scan values into account when
            %calculating based on time
            if strcmpi(app.LoopsOrDuration.Value,'Duration (s)')
                app.ex.pulseBlaster.nTotalLoops = 1;
                app.ex.pulseBlaster = calculateDuration(app.ex.pulseBlaster,'user');
                app.ex.pulseBlaster.nTotalLoops = floor(app.TotalLoops.Value/app.ex.pulseBlaster.sequenceDurations.user.totalSeconds);
            else
                app.ex.pulseBlaster.nTotalLoops = app.TotalLoops.Value;
            end
        end

        % Value changed function: DataType
        function DataTypeValueChanged(app, event)
            if ~isempty(app.ex.DAQ)
                app.ex.DAQ.activeDataChannel = app.DataType.Value;
            end
            ScanUpdate(app)
        end

        % Value changed function: ContrastType
        function ContrastTypeValueChanged(app, event)
            DisplayUpdate(app)
        end

        % Value changed function: TempdatapointdeviationEditField
        function TempdatapointdeviationEditFieldValueChanged(app, event)
            %**Added temporary function to change data point deviation
            %tolerance
            app.ex.nPointsTolerance = app.TempdatapointdeviationEditField.Value;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LeftPanel = uipanel(app.UIFigure);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.ConnectionsTab = uitab(app.TabGroup);
            app.ConnectionsTab.Title = 'Connections';
            app.GridLayout2 = uigridlayout(app.ConnectionsTab);
            app.StageLoad = uibutton(app.GridLayout2, 'push');
            app.StageLoad.ButtonPushedFcn = createCallbackFcn(app, @StageLoadButtonPushed, true);
            app.StageLoad.Text = 'Load';
            app.StageUnload = uibutton(app.GridLayout2, 'push');
            app.StageUnload.ButtonPushedFcn = createCallbackFcn(app, @StageUnloadButtonPushed, true);
            app.StageUnload.Text = 'Unload';
            app.StageLamp = uilamp(app.GridLayout2);
            app.PulseBlasterLoad = uibutton(app.GridLayout2, 'push');
            app.PulseBlasterLoad.ButtonPushedFcn = createCallbackFcn(app, @PulseBlasterLoadButtonPushed, true);
            app.PulseBlasterLoad.Text = 'Load';
            app.SRSRFLoad = uibutton(app.GridLayout2, 'push');
            app.SRSRFLoad.ButtonPushedFcn = createCallbackFcn(app, @SRSRFLoadButtonPushed, true);
            app.SRSRFLoad.Text = 'Load';
            app.PulseBlasterLamp = uilamp(app.GridLayout2);
            app.SRSRFLamp = uilamp(app.GridLayout2);
            app.DAQLamp = uilamp(app.GridLayout2);
            app.PulseBlasterUnload = uibutton(app.GridLayout2, 'push');
            app.PulseBlasterUnload.ButtonPushedFcn = createCallbackFcn(app, @PulseBlasterUnloadButtonPushed, true);
            app.PulseBlasterUnload.Text = 'Unload';
            app.SRSRFUnload = uibutton(app.GridLayout2, 'push');
            app.SRSRFUnload.ButtonPushedFcn = createCallbackFcn(app, @SRSRFUnloadButtonPushed, true);
            app.SRSRFUnload.Text = 'Unload';
            app.DAQLoad = uibutton(app.GridLayout2, 'push');
            app.DAQLoad.ButtonPushedFcn = createCallbackFcn(app, @DAQLoadButtonPushed, true);
            app.DAQLoad.Text = 'Load';
            app.DAQUnload = uibutton(app.GridLayout2, 'push');
            app.DAQUnload.ButtonPushedFcn = createCallbackFcn(app, @DAQUnloadButtonPushed, true);
            app.DAQUnload.Text = 'Unload';
            app.SproutLaserLoad = uibutton(app.GridLayout2, 'push');
            app.SproutLaserLoad.ButtonPushedFcn = createCallbackFcn(app, @SproutLaserLoadButtonPushed, true);
            app.SproutLaserLoad.Text = 'Load';
            app.SproutLaserUnload = uibutton(app.GridLayout2, 'push');
            app.SproutLaserUnload.ButtonPushedFcn = createCallbackFcn(app, @SproutLaserUnloadButtonPushed, true);
            app.SproutLaserUnload.Text = 'Unload';
            app.SproutLaserLamp = uilamp(app.GridLayout2);
            app.StageLabel = uilabel(app.GridLayout2);
            app.StageLabel.Text = 'Stage';
            app.PulseBlasterLabel = uilabel(app.GridLayout2);
            app.PulseBlasterLabel.Text = 'Pulse Blaster';
            app.SproutLaserLabel = uilabel(app.GridLayout2);
            app.SproutLaserLabel.Text = 'Sprout Laser';
            app.SRSRFLabel = uilabel(app.GridLayout2);
            app.SRSRFLabel.Text = 'SRS RF';
            app.DAQLabel = uilabel(app.GridLayout2);
            app.DAQLabel.Text = 'DAQ';
            app.ConfigFileNameLabel = uilabel(app.GridLayout2);
            app.ConfigFileNameLabel.Text = 'Config File Name';
            app.StageConfig = uieditfield(app.GridLayout2, 'text');
            app.PulseBlasterConfig = uieditfield(app.GridLayout2, 'text');
            app.SproutLaserConfig = uieditfield(app.GridLayout2, 'text');
            app.SRSRFConfig = uieditfield(app.GridLayout2, 'text');
            app.DAQConfig = uieditfield(app.GridLayout2, 'text');
            app.WindfreakRFLabel = uilabel(app.GridLayout2);
            app.WindfreakRFLabel.Text = 'Windfreak RF';
            app.WindfreakRFConfig = uieditfield(app.GridLayout2, 'text');
            app.WindfreakRFUnload = uibutton(app.GridLayout2, 'push');
            app.WindfreakRFUnload.ButtonPushedFcn = createCallbackFcn(app, @WindfreakRFUnloadButtonPushed, true);
            app.WindfreakRFUnload.Text = 'Unload';
            app.WindfreakRFLamp = uilamp(app.GridLayout2);
            app.WindfreakRFLoad = uibutton(app.GridLayout2, 'push');
            app.WindfreakRFLoad.ButtonPushedFcn = createCallbackFcn(app, @WindfreakRFLoadButtonPushed, true);
            app.WindfreakRFLoad.Text = 'Load';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.GridLayout4 = uigridlayout(app.SettingsTab);
            app.SetLaserPower = uieditfield(app.GridLayout4, 'numeric');
            app.SetLaserPower.ValueChangedFcn = createCallbackFcn(app, @SetLaserPowerValueChanged, true);
            app.LaserLabel_2 = uilabel(app.GridLayout4);
            app.LaserLabel_2.Text = 'Sprout Laser';
            app.ToggleLaser = uiswitch(app.GridLayout4, 'slider');
            app.ToggleLaser.Items = {'off', 'on'};
            app.ToggleLaser.ValueChangedFcn = createCallbackFcn(app, @ToggleLaserValueChanged, true);
            app.ToggleLaser.Value = 'off';
            app.RefreshAppButton = uibutton(app.GridLayout4, 'push');
            app.RefreshAppButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshAppButtonPushed, true);
            app.RefreshAppButton.Text = 'Refresh App';
            app.SetpowermWLabel = uilabel(app.GridLayout4);
            app.SetpowermWLabel.Text = {'Set power'; '(mW)'};
            app.PowerreadingmWLabel = uilabel(app.GridLayout4);
            app.PowerreadingmWLabel.Text = {'Power'; 'reading (mW)'};
            app.LaserPowerReading = uieditfield(app.GridLayout4, 'numeric');
            app.LaserPowerReading.Editable = 'off';
            app.RefreshLaserPowerReading = uibutton(app.GridLayout4, 'push');
            app.RefreshLaserPowerReading.ButtonPushedFcn = createCallbackFcn(app, @RefreshLaserPowerReadingButtonPushed, true);
            app.RefreshLaserPowerReading.Text = {'Refresh'; 'power'; 'reading'};
            app.CollectdatacontinuouslyButton = uibutton(app.GridLayout4, 'push');
            app.CollectdatacontinuouslyButton.ButtonPushedFcn = createCallbackFcn(app, @CollectdatacontinuouslyButtonPushed, true);
            app.CollectdatacontinuouslyButton.Text = {'Collect data'; 'continuously'};
            app.CopyappobjecttoworkspaceButton = uibutton(app.GridLayout4, 'push');
            app.CopyappobjecttoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @CopyappobjecttoworkspaceButtonPushed, true);
            app.CopyappobjecttoworkspaceButton.Text = {'Copy app object '; 'to workspace'};
            app.SRS_RFFrequency = uieditfield(app.GridLayout4, 'numeric');
            app.SRS_RFFrequency.ValueChangedFcn = createCallbackFcn(app, @SRS_RFFrequencyValueChanged, true);
            app.SRS_RFAmplitude = uieditfield(app.GridLayout4, 'numeric');
            app.SRS_RFAmplitude.ValueChangedFcn = createCallbackFcn(app, @SRS_RFAmplitudeValueChanged, true);
            app.DifferentiatesignalandreferenceCheckBox = uicheckbox(app.GridLayout4);
            app.DifferentiatesignalandreferenceCheckBox.ValueChangedFcn = createCallbackFcn(app, @DifferentiatesignalandreferenceCheckBoxValueChanged, true);
            app.DifferentiatesignalandreferenceCheckBox.Text = {'  Differentiate '; '  signal and'; '  reference'};
            app.DifferentiatesignalandreferenceCheckBox.Value = true;
            app.WaitForLaserEquilibrium = uicheckbox(app.GridLayout4);
            app.WaitForLaserEquilibrium.ValueChangedFcn = createCallbackFcn(app, @WaitForLaserEquilibriumValueChanged, true);
            app.WaitForLaserEquilibrium.Text = {'  Wait for laser'; '  equilibrium'};
            app.OffWhenDeleted = uicheckbox(app.GridLayout4);
            app.OffWhenDeleted.ValueChangedFcn = createCallbackFcn(app, @OffWhenDeletedValueChanged, true);
            app.OffWhenDeleted.Text = {'  Turn laser off'; '  when unloaded'};
            app.SRS_RFModulationDropDown = uidropdown(app.GridLayout4);
            app.SRS_RFModulationDropDown.Items = {'Off', 'I/Q', 'amplitude'};
            app.SRS_RFModulationDropDown.ValueChangedFcn = createCallbackFcn(app, @SRS_RFModulationDropDownValueChanged, true);
            app.SRS_RFModulationDropDown.Value = 'Off';
            app.NumberofpointsforcontinuouscollectionLabel = uilabel(app.GridLayout4);
            app.NumberofpointsforcontinuouscollectionLabel.Text = {'Number of points for '; 'continuous collection'};
            app.NumberofpointsforcontinuouscollectionEditField = uieditfield(app.GridLayout4, 'numeric');
            app.RFFrequencyGHzLabel_2 = uilabel(app.GridLayout4);
            app.RFFrequencyGHzLabel_2.Text = 'Frequency (GHz)      ';
            app.windfreakRFFrequency = uieditfield(app.GridLayout4, 'numeric');
            app.windfreakRFFrequency.ValueChangedFcn = createCallbackFcn(app, @windfreakRFFrequencyValueChanged, true);
            app.windfreakRFAmplitude = uieditfield(app.GridLayout4, 'numeric');
            app.windfreakRFAmplitude.ValueChangedFcn = createCallbackFcn(app, @windfreakRFAmplitudeValueChanged, true);
            app.ModulationLabel = uilabel(app.GridLayout4);
            app.ModulationLabel.Text = 'Modulation     ';
            app.windfreakRFModulationDropDown = uidropdown(app.GridLayout4);
            app.windfreakRFModulationDropDown.Items = {'Off', 'I/Q', 'amplitude'};
            app.windfreakRFModulationDropDown.ValueChangedFcn = createCallbackFcn(app, @WindfreakRFwindfreakRFModulationDropDownValueChanged, true);
            app.windfreakRFModulationDropDown.Value = 'Off';
            app.SRSRFsettingsLabel = uilabel(app.GridLayout4);
            app.SRSRFsettingsLabel.Text = 'SRS RF settings';
            app.WindfreakRFsettingsLabel = uilabel(app.GridLayout4);
            app.WindfreakRFsettingsLabel.Text = 'Windfreak RF settings';
            app.AmplitudedBmLabel = uilabel(app.GridLayout4);
            app.AmplitudedBmLabel.Text = 'Amplitude (dBm)';
            app.DatapointdurationLabel = uilabel(app.GridLayout4);
            app.DatapointdurationLabel.Text = {'Data point '; 'duration (ns)'};
            app.DatapointdurationnsEditField = uieditfield(app.GridLayout4, 'numeric');
            app.PositionTab = uitab(app.TabGroup);
            app.PositionTab.Title = 'Position';
            app.GridLayout3 = uigridlayout(app.PositionTab);
            app.XDown = uibutton(app.GridLayout3, 'push');
            app.XDown.ButtonPushedFcn = createCallbackFcn(app, @XDownButtonPushed, true);
            app.XDown.Text = '<';
            app.XUp = uibutton(app.GridLayout3, 'push');
            app.XUp.ButtonPushedFcn = createCallbackFcn(app, @XUpButtonPushed, true);
            app.XUp.Text = '>';
            app.XIncrement = uieditfield(app.GridLayout3, 'numeric');
            app.XPosition = uieditfield(app.GridLayout3, 'numeric');
            app.XPosition.Limits = [-12600 12600];
            app.XPosition.ValueChangedFcn = createCallbackFcn(app, @XPositionValueChanged, true);
            app.AbsolutepositionLabel = uilabel(app.GridLayout3);
            app.AbsolutepositionLabel.Text = {'Absolute'; 'position'};
            app.IncrementLabel = uilabel(app.GridLayout3);
            app.IncrementLabel.Text = 'Increment';
            app.YIncrement = uieditfield(app.GridLayout3, 'numeric');
            app.YPosition = uieditfield(app.GridLayout3, 'numeric');
            app.YPosition.Limits = [-12600 12600];
            app.YPosition.ValueChangedFcn = createCallbackFcn(app, @YPositionValueChanged, true);
            app.StagetolerancemLabel = uilabel(app.GridLayout3);
            app.StagetolerancemLabel.Text = {'Stage'; 'tolerance'; '(μm)'};
            app.StageTolerance = uieditfield(app.GridLayout3, 'numeric');
            app.StageTolerance.Limits = [0 Inf];
            app.StageTolerance.ValueChangedFcn = createCallbackFcn(app, @StageToleranceValueChanged, true);
            app.StageTolerance.Value = 0.01;
            app.AxisLabel = uilabel(app.GridLayout3);
            app.AxisLabel.Text = 'Axis';
            app.StagexLabel = uilabel(app.GridLayout3);
            app.StagexLabel.Text = 'Stage x';
            app.StageyLabel = uilabel(app.GridLayout3);
            app.StageyLabel.Text = 'Stage y';
            app.StagezLabel = uilabel(app.GridLayout3);
            app.StagezLabel.Text = 'Stage z';
            app.MovenegativeLabel = uilabel(app.GridLayout3);
            app.MovenegativeLabel.Text = {'Move'; 'negative'};
            app.MovepositiveLabel = uilabel(app.GridLayout3);
            app.MovepositiveLabel.Text = {'Move'; 'positive'};
            app.YDown = uibutton(app.GridLayout3, 'push');
            app.YDown.ButtonPushedFcn = createCallbackFcn(app, @YDownButtonPushed, true);
            app.YDown.Text = '<';
            app.ZDown = uibutton(app.GridLayout3, 'push');
            app.ZDown.ButtonPushedFcn = createCallbackFcn(app, @ZDownButtonPushed, true);
            app.ZDown.Text = '<';
            app.YUp = uibutton(app.GridLayout3, 'push');
            app.YUp.ButtonPushedFcn = createCallbackFcn(app, @YUpButtonPushed, true);
            app.YUp.Text = '>';
            app.ZUp = uibutton(app.GridLayout3, 'push');
            app.ZUp.ButtonPushedFcn = createCallbackFcn(app, @ZUpButtonPushed, true);
            app.ZUp.Text = '>';
            app.ZPosition = uieditfield(app.GridLayout3, 'numeric');
            app.ZPosition.Limits = [-12600 12600];
            app.ZPosition.ValueChangedFcn = createCallbackFcn(app, @ZPositionValueChanged, true);
            app.ZIncrement = uieditfield(app.GridLayout3, 'numeric');
            app.AddressEditField_2Label = uilabel(app.GridLayout3);
            app.AddressEditField_2Label.Text = 'Address';
            app.StageAddress = uieditfield(app.GridLayout3, 'numeric');
            app.StageAddress.Limits = [1 Inf];
            app.StageAddress.Value = 1;
            app.StageOptimizationInstructions = uitable(app.GridLayout3);
            app.StageOptimizationInstructions.ColumnName = {'Stage'; 'Radius'; 'Steps'};
            app.StageOptimizationInstructions.RowName = {};
            app.StageOptimizationInstructions.CellEditCallback = createCallbackFcn(app, @StageOptimizationInstructionsCellEdit, true);
            app.AddOptimizationStep = uibutton(app.GridLayout3, 'push');
            app.AddOptimizationStep.ButtonPushedFcn = createCallbackFcn(app, @AddOptimizationStepPushed, true);
            app.AddOptimizationStep.Text = {'Add'; 'step'};
            app.DeleteOptimizationStep = uibutton(app.GridLayout3, 'push');
            app.DeleteOptimizationStep.ButtonPushedFcn = createCallbackFcn(app, @DeleteOptimizationStepPushed, true);
            app.DeleteOptimizationStep.Text = {'Delete'; 'step'};
            app.StageAutoOptimization = uicheckbox(app.GridLayout3);
            app.StageAutoOptimization.ValueChangedFcn = createCallbackFcn(app, @StageAutoOptimizationValueChanged, true);
            app.StageAutoOptimization.Text = {'Auto-optimize'; 'during scan'};
            app.TimebetweenoptimizationsminLabel = uilabel(app.GridLayout3);
            app.TimebetweenoptimizationsminLabel.Text = {'Time between'; 'optimizations (min)'};
            app.OptimizationInterval = uieditfield(app.GridLayout3, 'numeric');
            app.OptimizationInterval.ValueChangedFcn = createCallbackFcn(app, @OptimizationIntervalValueChanged, true);
            app.BeginmanualoptimizationButton = uibutton(app.GridLayout3, 'push');
            app.BeginmanualoptimizationButton.ButtonPushedFcn = createCallbackFcn(app, @BeginmanualoptimizationButtonPushed, true);
            app.BeginmanualoptimizationButton.Text = 'Begin manual optimization';
            app.NumberofmanualoptimizationsLabel = uilabel(app.GridLayout3);
            app.NumberofmanualoptimizationsLabel.Text = {'Number of manual'; ' optimizations'};
            app.Numberofmanualoptimizations = uieditfield(app.GridLayout3, 'numeric');
            app.OptimizationmethodDropDownLabel = uilabel(app.GridLayout3);
            app.OptimizationmethodDropDownLabel.Text = {'Optimization'; 'method'};
            app.OptimizationmethodDropDown = uidropdown(app.GridLayout3);
            app.OptimizationmethodDropDown.Items = {'max value', 'gaussian'};
            app.OptimizationmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @OptimizationmethodDropDownValueChanged, true);
            app.OptimizationmethodDropDown.Value = 'max value';
            app.PausedurationforfinestagemovementsmsLabel = uilabel(app.GridLayout3);
            app.PausedurationforfinestagemovementsmsLabel.Text = {'Settling duration'; 'for stage'; 'movements (ms)'};
            app.StageSettlingTime = uieditfield(app.GridLayout3, 'numeric');
            app.StageSettlingTime.ValueChangedFcn = createCallbackFcn(app, @StageSettlingTimeValueChanged, true);
            app.ResetFineAxisLabel = uilabel(app.GridLayout3);
            app.ResetFineAxisLabel.Text = {'Reset'; 'Fine Axis'};
            app.XFineReset = uibutton(app.GridLayout3, 'push');
            app.XFineReset.ButtonPushedFcn = createCallbackFcn(app, @XFineResetPushed, true);
            app.XFineReset.Text = {'Reset'; 'fine x'};
            app.YFineReset = uibutton(app.GridLayout3, 'push');
            app.YFineReset.ButtonPushedFcn = createCallbackFcn(app, @YFineResetButtonPushed, true);
            app.YFineReset.Text = {'Reset'; 'fine y'};
            app.ZFineReset = uibutton(app.GridLayout3, 'push');
            app.ZFineReset.ButtonPushedFcn = createCallbackFcn(app, @ZFineResetButtonPushed, true);
            app.ZFineReset.Text = {'Reset'; 'fine z'};
            app.OptimizationparameterLabel = uilabel(app.GridLayout3);
            app.OptimizationparameterLabel.Text = {'Optimization'; 'parameter'};
            app.OptimizationparameterDropDown = uidropdown(app.GridLayout3);
            app.OptimizationparameterDropDown.Items = {'Counts (RF off)', 'Counts (RF on)', 'Contrast'};
            app.OptimizationparameterDropDown.ValueChangedFcn = createCallbackFcn(app, @OptimizationparameterDropDownValueChanged, true);
            app.OptimizationparameterDropDown.Value = 'Counts (RF off)';
            app.OptimizationTimer = uicheckbox(app.GridLayout3);
            app.OptimizationTimer.ValueChangedFcn = createCallbackFcn(app, @OptimizationTimerValueChanged, true);
            app.OptimizationTimer.Text = {'Use timer to '; 'force '; 'optimization'};
            app.OptimizationPercentageCheck = uicheckbox(app.GridLayout3);
            app.OptimizationPercentageCheck.ValueChangedFcn = createCallbackFcn(app, @OptimizationPercentageCheckValueChanged, true);
            app.OptimizationPercentageCheck.Text = {'Use percentage '; 'to force'; ' optimization'};
            app.TimebetweenoptimizationsminLabel_2 = uilabel(app.GridLayout3);
            app.TimebetweenoptimizationsminLabel_2.Text = {'Percentage to'; 'force '; 'optimization'};
            app.OptimizationPercentage = uieditfield(app.GridLayout3, 'numeric');
            app.OptimizationPercentage.ValueChangedFcn = createCallbackFcn(app, @OptimizationPercentageValueChanged, true);
            app.OptimizationInsertorReplace = uidropdown(app.GridLayout3);
            app.OptimizationInsertorReplace.Items = {'Insert', 'Replace'};
            app.OptimizationInsertorReplace.Value = 'Insert';
            app.ScansTab = uitab(app.TabGroup);
            app.ScansTab.Title = 'Scans';
            app.GridLayout6 = uigridlayout(app.ScansTab);
            app.ScanningTable = uitable(app.GridLayout6);
            app.ScanningTable.ColumnName = {'Type'; 'Start'; 'End'; '# of steps'; 'Step size'; 'Pulse addresses'; 'notes'};
            app.ScanningTable.RowName = {};
            app.ScanningTable.ColumnEditable = true;
            app.ScanningTable.CellEditCallback = createCallbackFcn(app, @ScanningTableCellEdit, true);
            app.AddScanStep = uibutton(app.GridLayout6, 'push');
            app.AddScanStep.ButtonPushedFcn = createCallbackFcn(app, @AddScanStepPushed, true);
            app.AddScanStep.Text = 'Add scan';
            app.DeleteScanStep = uibutton(app.GridLayout6, 'push');
            app.DeleteScanStep.ButtonPushedFcn = createCallbackFcn(app, @DeleteScanStepPushed, true);
            app.DeleteScanStep.Text = 'Delete scan';
            app.AddressEditField_3Label = uilabel(app.GridLayout6);
            app.AddressEditField_3Label.Text = 'Address';
            app.ScanAddress = uieditfield(app.GridLayout6, 'numeric');
            app.ScanAddress.Limits = [1 10];
            app.ScanAddress.RoundFractionalValues = 'on';
            app.ScanAddress.Value = 1;
            app.DefaultchangeDropDownLabel = uilabel(app.GridLayout6);
            app.DefaultchangeDropDownLabel.Text = 'Default change';
            app.DefaultScanChange = uidropdown(app.GridLayout6);
            app.DefaultScanChange.Items = {'# of steps', 'Step size'};
            app.DefaultScanChange.Value = '# of steps';
            app.PossibletypesTextAreaLabel = uilabel(app.GridLayout6);
            app.PossibletypesTextAreaLabel.Text = 'Possible types';
            app.PossibletypesTextArea = uitextarea(app.GridLayout6);
            app.PossibletypesTextArea.Editable = 'off';
            app.PossibletypesTextArea.Value = {'Stage X'; 'Stage Y'; 'Stage Z'; 'RF frequency'; 'RF amplitude'; 'Pulse duration'};
            app.RandomizescanCheckBox = uicheckbox(app.GridLayout6);
            app.RandomizescanCheckBox.ValueChangedFcn = createCallbackFcn(app, @RandomizescanCheckBoxValueChanged, true);
            app.RandomizescanCheckBox.Text = 'Randomize scan points';
            app.MaxIterationsEditFieldLabel = uilabel(app.GridLayout6);
            app.MaxIterationsEditFieldLabel.Text = 'Max Iterations';
            app.MaxIterations = uieditfield(app.GridLayout6, 'numeric');
            app.MaxIterations.Limits = [0 Inf];
            app.MaxIterations.Value = 1;
            app.IterationstypeDropDownLabel = uilabel(app.GridLayout6);
            app.IterationstypeDropDownLabel.Text = 'Iterations type';
            app.IterationsType = uidropdown(app.GridLayout6);
            app.IterationsType.Items = {'Number', 'Time (min)', 'Perpetual'};
            app.IterationsType.Value = 'Number';
            app.NonlinearscanfilenameLabel = uilabel(app.GridLayout6);
            app.NonlinearscanfilenameLabel.Text = {'Nonlinear scan'; 'file name'};
            app.NonlinearscanfilenameEditField = uieditfield(app.GridLayout6, 'text');
            app.DataTypeLabel = uilabel(app.GridLayout6);
            app.DataTypeLabel.Text = 'Data Type';
            app.DataType = uidropdown(app.GridLayout6);
            app.DataType.Items = {'Analog', 'Digital'};
            app.DataType.ItemsData = {'Data analog', 'Data counter'};
            app.DataType.ValueChangedFcn = createCallbackFcn(app, @DataTypeValueChanged, true);
            app.DataType.Value = 'Data analog';
            app.OutputTab = uitab(app.TabGroup);
            app.OutputTab.Title = 'Output';
            app.GridLayout5 = uigridlayout(app.OutputTab);
            app.PlotLimits = uitable(app.GridLayout5);
            app.PlotLimits.ColumnName = {'Axis'; 'Minimum'; 'Maximum'; 'Override'; 'Offset'; 'Incorporate Offset'};
            app.PlotLimits.RowName = {};
            app.PlotLimits.ColumnEditable = [false true true true true true];
            app.PlotLimits.CellEditCallback = createCallbackFcn(app, @PlotLimitsCellEdit, true);
            app.ScanStepTable = uitable(app.GridLayout5);
            app.ScanStepTable.ColumnName = {'Scan Type'; 'Step to View'};
            app.ScanStepTable.RowName = {};
            app.ScanStepTable.ColumnEditable = [false true];
            app.ScanStepTable.CellEditCallback = createCallbackFcn(app, @ScanStepTableCellEdit, true);
            app.ScanProgress = uitextarea(app.GridLayout5);
            app.ScanProgress.Editable = 'off';
            app.ScanProgress.Value = {'Shows completion of each scan. If it is a randomized scan, shows linear completion'};
            app.ForcesquareaspectratioforimagesCheckBox = uicheckbox(app.GridLayout5);
            app.ForcesquareaspectratioforimagesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ForcesquareaspectratioforimagesCheckBoxValueChanged, true);
            app.ForcesquareaspectratioforimagesCheckBox.Text = {'Force square aspect ratio'; 'for images'};
            app.DisplayIterations = uidropdown(app.GridLayout5);
            app.DisplayIterations.Items = {'Display average across iterations', 'Display current iteration', 'Display prior iterations'};
            app.DisplayIterations.ItemsData = {'Average', 'Current', 'Prior'};
            app.DisplayIterations.ValueChangedFcn = createCallbackFcn(app, @DisplayIterationsValueChanged, true);
            app.DisplayIterations.Value = 'Average';
            app.UseStaticColorLimits = uicheckbox(app.GridLayout5);
            app.UseStaticColorLimits.ValueChangedFcn = createCallbackFcn(app, @UseStaticColorLimitsValueChanged, true);
            app.UseStaticColorLimits.Text = {'Use static color '; 'limits'};
            app.StaticColorLimits = uitable(app.GridLayout5);
            app.StaticColorLimits.ColumnName = {'Static color limit'; 'Value'};
            app.StaticColorLimits.RowName = {};
            app.StaticColorLimits.ColumnEditable = [false true];
            app.StaticColorLimits.CellEditCallback = createCallbackFcn(app, @StaticColorLimitsCellEdit, true);
            app.ScanforxaxisLabel = uilabel(app.GridLayout5);
            app.ScanforxaxisLabel.Text = 'Scan # for x axis';
            app.xAxisScanNum = uieditfield(app.GridLayout5, 'numeric');
            app.xAxisScanNum.Limits = [0 Inf];
            app.xAxisScanNum.RoundFractionalValues = 'on';
            app.xAxisScanNum.ValueChangedFcn = createCallbackFcn(app, @xAxisScanNumValueChanged, true);
            app.xAxisScanNum.Value = 1;
            app.ScanforyaxisLabel = uilabel(app.GridLayout5);
            app.ScanforyaxisLabel.Text = 'Scan # for y axis';
            app.yAxisScanNum = uieditfield(app.GridLayout5, 'numeric');
            app.yAxisScanNum.Limits = [0 Inf];
            app.yAxisScanNum.RoundFractionalValues = 'on';
            app.yAxisScanNum.ValueChangedFcn = createCallbackFcn(app, @yAxisScanNumValueChanged, true);
            app.ContrasttypeDropDownLabel = uilabel(app.GridLayout5);
            app.ContrasttypeDropDownLabel.Text = 'Contrast type';
            app.ContrastType = uidropdown(app.GridLayout5);
            app.ContrastType.Items = {'Ref - Sig', '(Ref - Sig)/Ref', '(Ref - Sig)/Sig', '(Ref - Sig)/(Ref + Sig)'};
            app.ContrastType.ValueChangedFcn = createCallbackFcn(app, @ContrastTypeValueChanged, true);
            app.ContrastType.Value = '(Ref - Sig)/Ref';
            app.ConverttocountssMaynotperfectlycalculateforsmalltimesCheckBox = uicheckbox(app.GridLayout5);
            app.ConverttocountssMaynotperfectlycalculateforsmalltimesCheckBox.Text = {'Convert to counts/s '; '**May not perfectly '; 'calculate for small times'};
            app.ForceplotupdateButton = uibutton(app.GridLayout5, 'push');
            app.ForceplotupdateButton.ButtonPushedFcn = createCallbackFcn(app, @ForceplotupdateButtonPushed, true);
            app.ForceplotupdateButton.Text = 'Force plot update';
            app.ThingToPlot = uidropdown(app.GridLayout5);
            app.ThingToPlot.Items = {'Plot contrast', 'Plot signal', 'Plot reference', 'Plot pulse sequence', 'Plot SNR'};
            app.ThingToPlot.ItemsData = {'Contrast', 'Signal', 'Reference', 'PulseSequence', 'SNR'};
            app.ThingToPlot.ValueChangedFcn = createCallbackFcn(app, @ThingToPlotValueChanged, true);
            app.ThingToPlot.Value = 'Contrast';
            app.ScanforxaxisLabel_2 = uilabel(app.GridLayout5);
            app.ScanforxaxisLabel_2.Text = 'Pulse # to plot';
            app.PulseNumToPlot = uieditfield(app.GridLayout5, 'numeric');
            app.PulseNumToPlot.Limits = [0 Inf];
            app.PulseNumToPlot.RoundFractionalValues = 'on';
            app.PulseNumToPlot.ValueChangedFcn = createCallbackFcn(app, @PulseNumToPlotValueChanged, true);
            app.PulseNumToPlot.Value = 1;
            app.BaselineSubtractionEditFieldLabel = uilabel(app.GridLayout5);
            app.BaselineSubtractionEditFieldLabel.Text = 'Baseline Subtraction';
            app.BaselineSubtraction = uieditfield(app.GridLayout5, 'numeric');
            app.SaveLoadTab = uitab(app.TabGroup);
            app.SaveLoadTab.Title = 'Save/Load';
            app.GridLayout9 = uigridlayout(app.SaveLoadTab);
            app.BoxaddressEditFieldLabel = uilabel(app.GridLayout9);
            app.BoxaddressEditFieldLabel.Text = 'Box address';
            app.BoxAddress = uieditfield(app.GridLayout9, 'text');
            app.BoxAddress.ValueChangedFcn = createCallbackFcn(app, @BoxAddressValueChanged, true);
            app.BoxAddress.Value = 'C:\Users\User\Box';
            app.SavenameLabel = uilabel(app.GridLayout9);
            app.SavenameLabel.Text = {'Save name'; ''};
            app.SaveFolderName = uieditfield(app.GridLayout9, 'text');
            app.SaveButton = uibutton(app.GridLayout9, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.GridLayout9, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SavedUserNotes = uitextarea(app.GridLayout9);
            app.SavedUserNotes.Value = {'User notes'; ''};
            app.DataSetupsSwitch = uiswitch(app.GridLayout9, 'slider');
            app.DataSetupsSwitch.Items = {'Data', 'Setups'};
            app.DataSetupsSwitch.ValueChangedFcn = createCallbackFcn(app, @DataSetupsSwitchValueChanged, true);
            app.DataSetupsSwitch.Value = 'Setups';
            app.LoadDates = uilistbox(app.GridLayout9);
            app.LoadDates.Items = {'Setup Versions', 'v# - yyyy/mm/dd', ''};
            app.LoadDates.ValueChangedFcn = createCallbackFcn(app, @LoadDatesValueChanged, true);
            app.LoadDates.Value = 'Setup Versions';
            app.LoadNames = uilistbox(app.GridLayout9);
            app.LoadNames.Items = {'Setup Names', 'any string'};
            app.LoadNames.ValueChangedFcn = createCallbackFcn(app, @LoadNamesValueChanged, true);
            app.LoadNames.Value = 'Setup Names';
            app.UserName = uidropdown(app.GridLayout9);
            app.UserName.Items = {''};
            app.UserName.ValueChangedFcn = createCallbackFcn(app, @UserNameValueChanged, true);
            app.UserName.Value = '';
            app.PulseSequenceTab = uitab(app.TabGroup);
            app.PulseSequenceTab.Title = 'Pulse Sequence';
            app.GridLayout10 = uigridlayout(app.PulseSequenceTab);
            app.PulseSequenceTable = uitable(app.GridLayout10);
            app.PulseSequenceTable.ColumnName = {'#'; 'Active Channels'; 'Binary'; '# Output'; 'Duration (ns)'; 'Direction Type'; 'Context Info'; 'notes'};
            app.PulseSequenceTable.RowName = {};
            app.PulseSequenceTable.ColumnEditable = true;
            app.PulseSequenceTable.CellEditCallback = createCallbackFcn(app, @PulseSequenceTableCellEdit, true);
            app.PulseAddressEditFieldLabel = uilabel(app.GridLayout10);
            app.PulseAddressEditFieldLabel.Text = 'Pulse Address';
            app.PulseAddress = uieditfield(app.GridLayout10, 'numeric');
            app.PulseAddress.Limits = [1 Inf];
            app.PulseAddress.RoundFractionalValues = 'on';
            app.PulseAddress.Value = 1;
            app.AddStepButton = uibutton(app.GridLayout10, 'state');
            app.AddStepButton.ValueChangedFcn = createCallbackFcn(app, @AddStepButtonValueChanged, true);
            app.AddStepButton.Text = 'Add Step';
            app.DeleteStepButton = uibutton(app.GridLayout10, 'state');
            app.DeleteStepButton.ValueChangedFcn = createCallbackFcn(app, @DeleteStepButtonValueChanged, true);
            app.DeleteStepButton.Text = 'Delete Step';
            app.LoopsOrDuration = uidropdown(app.GridLayout10);
            app.LoopsOrDuration.Items = {'# of Loops', 'Duration (s)'};
            app.LoopsOrDuration.Value = '# of Loops';
            app.TotalLoops = uieditfield(app.GridLayout10, 'numeric');
            app.TotalLoops.Limits = [0 Inf];
            app.TotalLoops.ValueChangedFcn = createCallbackFcn(app, @TotalLoopsValueChanged, true);
            app.TotalLoops.Value = 1000;
            app.SavePulseSequence = uibutton(app.GridLayout10, 'state');
            app.SavePulseSequence.ValueChangedFcn = createCallbackFcn(app, @SavePulseSequenceValueChanged, true);
            app.SavePulseSequence.Text = 'Save';
            app.LoadPulse = uibutton(app.GridLayout10, 'state');
            app.LoadPulse.ValueChangedFcn = createCallbackFcn(app, @LoadPulseValueChanged, true);
            app.LoadPulse.Text = 'Load';
            app.BoxaddressEditFieldLabel_2 = uilabel(app.GridLayout10);
            app.BoxaddressEditFieldLabel_2.Text = 'Box address';
            app.PulseBoxAddress = uieditfield(app.GridLayout10, 'text');
            app.PulseBoxAddress.ValueChangedFcn = createCallbackFcn(app, @PulseBoxAddressValueChanged, true);
            app.SavenameLabel_2 = uilabel(app.GridLayout10);
            app.SavenameLabel_2.Text = {'Save name'; ''};
            app.PulseSaveName = uieditfield(app.GridLayout10, 'text');
            app.PulseSequenceNames = uilistbox(app.GridLayout10);
            app.PulseSequenceNames.Items = {'Pulse Sequence Names'};
            app.PulseSequenceNames.ValueChangedFcn = createCallbackFcn(app, @PulseSequenceNamesValueChanged, true);
            app.PulseSequenceNames.Value = 'Pulse Sequence Names';
            app.PulseUserNotes = uitextarea(app.GridLayout10);
            app.PulseUserNotes.Placeholder = 'User Notes';
            app.CopytoButton = uibutton(app.GridLayout10, 'state');
            app.CopytoButton.ValueChangedFcn = createCallbackFcn(app, @CopytoButtonValueChanged, true);
            app.CopytoButton.Text = 'Copy to';
            app.CopyAddresses = uieditfield(app.GridLayout10, 'text');
            app.UserName2 = uidropdown(app.GridLayout10);
            app.UserName2.Items = {''};
            app.UserName2.ValueChangedFcn = createCallbackFcn(app, @UserName2ValueChanged, true);
            app.UserName2.Value = '';
            app.ObscureoutputsettingsTab = uitab(app.TabGroup);
            app.ObscureoutputsettingsTab.Title = 'Obscure output settings';
            app.GridLayout11 = uigridlayout(app.ObscureoutputsettingsTab);
            app.TempdatapointdeviationLabel = uilabel(app.GridLayout11);
            app.TempdatapointdeviationLabel.Text = {'Temp data'; 'point deviation'};
            app.TempdatapointdeviationEditField = uieditfield(app.GridLayout11, 'numeric');
            app.TempdatapointdeviationEditField.ValueChangedFcn = createCallbackFcn(app, @TempdatapointdeviationEditFieldValueChanged, true);
            app.RightPanel = uipanel(app.UIFigure);
            app.Panel = uipanel(app.RightPanel);
            app.GridLayout7 = uigridlayout(app.Panel);
            app.OutputPanel = uipanel(app.GridLayout7);
            app.OneDPlot = uiaxes(app.OutputPanel);
            xlabel(app.OneDPlot, 'X')
            ylabel(app.OneDPlot, 'Y')
            zlabel(app.OneDPlot, 'Z')
            app.OneDPlot.Visible = 'off';
            app.PulseSequencePlot = uiaxes(app.OutputPanel);
            xlabel(app.PulseSequencePlot, 'Time(ns)')
            zlabel(app.PulseSequencePlot, 'Z')
            app.PulseSequencePlot.Visible = 'off';
            app.TwoDPlot = uiaxes(app.OutputPanel);
            xlabel(app.TwoDPlot, 'X')
            ylabel(app.TwoDPlot, 'Y')
            zlabel(app.TwoDPlot, 'Z')
            app.TwoDPlot.Visible = 'off';
            app.StartButton = uibutton(app.GridLayout7, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ResetButton = uibutton(app.GridLayout7, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.OutputInfo = uitextarea(app.GridLayout7);
            app.OutputInfo.Editable = 'off';
            app.ColorBarPanel = uipanel(app.GridLayout7);
            app.StatusLamp = uilamp(app.GridLayout7);
            app.ScreenshotButton = uibutton(app.GridLayout7, 'push');
            app.ScreenshotButton.ButtonPushedFcn = createCallbackFcn(app, @ScreenshotButtonPushed, true);
            app.ScreenshotButton.Text = 'Screenshot';
            app.AbortButton = uibutton(app.GridLayout7, 'push');
            app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortButtonPushed, true);
            app.AbortButton.Text = 'Abort';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NV_ExperimentEditor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFunction)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end